classdef EditScreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MTTestPulseSpinnerLabel         matlab.ui.control.Label
        MTTestPulseSpinner              matlab.ui.control.Spinner
        MTConditioningPulseSpinnerLabel  matlab.ui.control.Label
        MTConditioningPulseSpinner      matlab.ui.control.Spinner
        OrLabel                         matlab.ui.control.Label
        RunTimesSpinner                 matlab.ui.control.Spinner
        RunTimesSpinnerLabel            matlab.ui.control.Label
        GroupingCategorizationDropDown  matlab.ui.control.DropDown
        GroupingCategorizationDropDownLabel  matlab.ui.control.Label
        HomeButton                      matlab.ui.control.Button
        DuplicateGroupingButton         matlab.ui.control.Button
        AddNewGroupingButton            matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        CoilChargeDelaymsSpinner        matlab.ui.control.Spinner
        CoilChargeDelaymsSpinnerLabel   matlab.ui.control.Label
        MTSpinner                       matlab.ui.control.Spinner
        MTSpinnerLabel                  matlab.ui.control.Label
        NumberofTrialsSpinner           matlab.ui.control.Spinner
        NumberofTrialsSpinnerLabel      matlab.ui.control.Label
        AdvancedOptionsCheckBox         matlab.ui.control.CheckBox
        PulseTypeDropDown               matlab.ui.control.DropDown
        PulseTypeDropDownLabel          matlab.ui.control.Label
        FileNametoSaveDatatoNewFileEditField  matlab.ui.control.EditField
        FileNametoSaveDatatoNewFileEditFieldLabel  matlab.ui.control.Label
        SaveCurrentAutomationtoExcelButton  matlab.ui.control.Button
        DeleteCurrentStepButton         matlab.ui.control.Button
        AddStepAboveButton              matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        AddNextAutomationStepButton     matlab.ui.control.Button
        InterPulseIntervalmsSpinner     matlab.ui.control.Spinner
        InterPulseIntervalmsSpinnerLabel  matlab.ui.control.Label
        InterTrialIntervalsSpinner      matlab.ui.control.Spinner
        InterTrialIntervalsSpinnerLabel  matlab.ui.control.Label
        NumberofPulsesLabel             matlab.ui.control.Label
        NumberofPulsesSpinner           matlab.ui.control.Spinner
        FrequencyofPulsesHzSpinner      matlab.ui.control.Spinner
        FrequencyofPulsesHzSpinnerLabel  matlab.ui.control.Label
        ExperimentalAutomatorPanelLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        % Variables used within multiple functions (app.Variable throughout
        % protocol)

        % Variables within the table
        IPI
        ITI
        NumberofPulsesperBurst
        NumberofTrials
        FrequencyofPulses
        MT
        CoilChargeDelay
        RunTime
        TestPulse
        TableGroup
        RandomorBlock
        GroupingNumber
        PulseType
        PulseTypeName
        StepNumber

        % Cell selected in table
        SelectedCell

        % Name of the file to save to
        FileName

        % Advanced Options varibales (if checked or not)
        AdvancedOptions
    end  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % File selected path and load into the
            appDir = fileparts(mfilename('fullpath'));
            seqLoc = [fileparts(appDir), '/sequences/'];
                        % Select file and load path
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0], 'CloseRequestFcn','');            
            [filename, pathname] = uigetfile(fullfile(seqLoc,'*.xls;*.xlsx'),'select file');
            delete(f); %delete the dummy figure
            fullpath = strcat(pathname,filename);
            ProtocolTable = readtable(fullpath);
            app.UITable.Data = table2cell(ProtocolTable);

            % Get table groups and last step number from the table
            app.TableGroup = app.UITable.Data(end,1);
            app.TableGroup = cell2mat(app.TableGroup);
            app.StepNumber = app.UITable.Data(end,3);
            app.StepNumber = cell2mat(app.StepNumber);
            
            % Initializing the variables within the table to zero
            app.IPI = 0;
            app.ITI = 0;
            app.NumberofPulsesperBurst = 0;
            app.NumberofTrials = 0;
            app.FrequencyofPulses = 0;
            app.MT = 0;
            app.CoilChargeDelay = 0;
            app.RunTime = 0;
            app.TestPulse = 0;

            % File name is the same as the file uploaded
            app.FileName = filename;

            % Set advanced options to 0 to start
            app.AdvancedOptions = 0;

            % Starting other table variables
            app.PulseType = 1;
            app.PulseTypeName = 'Single';

            app.RandomorBlock = 'Block';
        end

        % Value changed function: PulseTypeDropDown
        function PulseTypeDropDownValueChanged(app, event)
            app.PulseType = app.PulseTypeDropDown.Value;

            % Depending on the pulse type chosen in the drop down, it
            % changes the parameter options, locations, and resets all
            % parameters except %MT each time

            if app.PulseType == 1 % Single Pulse
                % Visible Options
                app.MTSpinner.Visible = 'on';
                app.MTSpinnerLabel.Visible = 'on';
                app.MTSpinner.Position = [171,524,123,27];
                app.MTSpinnerLabel.Position = [101,524,57,27];

                app.NumberofTrialsSpinner.Visible = 'on';
                app.NumberofTrialsSpinnerLabel.Visible = 'on';
                app.NumberofTrialsSpinner.Position = [171,464,121,27];
                app.NumberofTrialsSpinnerLabel.Position = [21,464,141,30];
                app.NumberofTrials = 0;
                app.NumberofTrialsSpinner.Value = 0;

                app.InterTrialIntervalsSpinner.Visible = 'on';
                app.InterTrialIntervalsSpinnerLabel.Visible = 'on';
                app.InterTrialIntervalsSpinner.Position = [171,393,121,27];
                app.InterTrialIntervalsSpinnerLabel.Position = [22,394,146,48];
                app.ITI = 0;
                app.InterTrialIntervalsSpinner.Value = 0;

                app.OrLabel.Visible = 'off';
    
                % Not Visible Options
                app.InterPulseIntervalmsSpinner.Visible = 'off';
                app.InterPulseIntervalmsSpinnerLabel.Visible = 'off';
                app.IPI = 0;
                app.InterPulseIntervalmsSpinner.Value = 0;

                app.NumberofPulsesSpinner.Visible = 'off';
                app.NumberofPulsesLabel.Visible = 'off';
                app.NumberofPulsesperBurst = 0;
                app.NumberofPulsesSpinner.Value = 0;

                app.FrequencyofPulsesHzSpinner.Visible = 'off';
                app.FrequencyofPulsesHzSpinnerLabel.Visible = 'off';
                app.FrequencyofPulses = 0;
                app.FrequencyofPulsesHzSpinner.Value = 0;

                app.RunTimesSpinner.Visible = 'off';
                app.RunTimesSpinnerLabel.Visible = 'off';
                app.RunTime = 0;
                app.RunTimesSpinner.Value = 0;

                app.MTConditioningPulseSpinner.Visible = 'off';
                app.MTConditioningPulseSpinnerLabel.Visible = 'off';

                app.MTTestPulseSpinner.Visible = 'off';
                app.MTTestPulseSpinnerLabel.Visible = 'off';
                app.TestPulse = 0;
                app.MTTestPulseSpinner.Value = 0;

                app.PulseTypeName = 'Single';


            elseif app.PulseType == 2 % Continuous Pulses
                % Visible Options
                app.MTSpinner.Visible = 'on';
                app.MTSpinnerLabel.Visible = 'on';
                app.MTSpinner.Position = [171,524,123,27];
                app.MTSpinnerLabel.Position = [101,524,57,27];

                app.NumberofPulsesSpinner.Visible = 'on';
                app.NumberofPulsesLabel.Visible = 'on';
                app.NumberofPulsesSpinner.Position = [171,464,121,27];
                app.NumberofPulsesLabel.Position = [21,464,141,30];
                app.NumberofPulsesperBurst = 0;
                app.NumberofPulsesSpinner.Value = 0;

                app.RunTimesSpinner.Visible = 'on';
                app.RunTimesSpinnerLabel.Visible = 'on';
                app.RunTimesSpinner.Position = [171,393,120,27];
                app.RunTimesSpinnerLabel.Position = [11,383,145,49];
                app.RunTime = 0;
                app.RunTimesSpinner.Value = 0;

                app.FrequencyofPulsesHzSpinner.Visible = 'on';
                app.FrequencyofPulsesHzSpinnerLabel.Visible = 'on';
                app.FrequencyofPulsesHzSpinner.Position = [171,325,120,27];
                app.FrequencyofPulsesHzSpinnerLabel.Position = [15,325,146,48];
                app.FrequencyofPulses = 0;
                app.FrequencyofPulsesHzSpinner.Value = 0;

                app.Image.Visible = 'off';
                app.OrLabel.Visible = 'on';

                % Not Visible Options
                app.InterPulseIntervalmsSpinner.Visible = 'off';
                app.InterPulseIntervalmsSpinnerLabel.Visible = 'off';
                app.IPI = 0;
                app.InterPulseIntervalmsSpinner.Value = 0;

                app.InterTrialIntervalsSpinner.Visible = 'off';
                app.InterTrialIntervalsSpinnerLabel.Visible = 'off';
                app.ITI = 0;
                app.InterTrialIntervalsSpinner.Value = 0;

                app.NumberofTrialsSpinner.Visible = 'off';
                app.NumberofTrialsSpinnerLabel.Visible = 'off';
                app.NumberofTrials = 0;
                app.NumberofTrialsSpinner.Value = 0;

                app.MTConditioningPulseSpinner.Visible = 'off';
                app.MTConditioningPulseSpinnerLabel.Visible = 'off';

                app.MTTestPulseSpinner.Visible = 'off';
                app.MTTestPulseSpinnerLabel.Visible = 'off';
                app.TestPulse = 0;
                app.MTTestPulseSpinner.Value = 0;

                app.PulseTypeName = 'Continuous';


            elseif app.PulseType == 3 % Theta Burst
                % Visible Options
                app.MTSpinner.Visible = 'on';
                app.MTSpinnerLabel.Visible = 'on';
                app.MTSpinner.Position = [171,524,123,27];
                app.MTSpinnerLabel.Position = [101,524,57,27];

                app.NumberofTrialsSpinner.Visible = 'on';
                app.NumberofTrialsSpinnerLabel.Visible = 'on';
                app.NumberofTrialsSpinner.Position = [171,464,121,27];
                app.NumberofTrialsSpinnerLabel.Position = [21,464,141,30];
                app.NumberofTrials = 0;
                app.NumberofTrialsSpinner.Value = 0;

                app.InterPulseIntervalmsSpinner.Visible = 'on';
                app.InterPulseIntervalmsSpinnerLabel.Visible = 'on';
                app.InterPulseIntervalmsSpinner.Position = [171,393,120,27];
                app.InterPulseIntervalmsSpinnerLabel.Position = [11,393,145,49];
                app.IPI = 0;
                app.InterPulseIntervalmsSpinner.Value = 0;

                app.NumberofPulsesSpinner.Visible = 'on';
                app.NumberofPulsesLabel.Visible = 'on';
                app.NumberofPulsesSpinner.Position = [171,325,120,27];
                app.NumberofPulsesLabel.Position = [15,325,146,48];
                app.NumberofPulsesperBurst = 0;
                app.NumberofPulsesSpinner.Value = 0;

                app.Image.Visible = 'off';
                app.OrLabel.Visible = 'off';

                % Not Visbible Options
                app.FrequencyofPulsesHzSpinner.Visible = 'off';
                app.FrequencyofPulsesHzSpinnerLabel.Visible = 'off';
                app.FrequencyofPulses = 0;
                app.FrequencyofPulsesHzSpinner.Value = 0;

                app.InterTrialIntervalsSpinner.Visible = 'off';
                app.InterTrialIntervalsSpinnerLabel.Visible = 'off';
                app.ITI = 0;
                app.InterTrialIntervalsSpinner.Value = 0;

                app.RunTimesSpinner.Visible = 'off';
                app.RunTimesSpinnerLabel.Visible = 'off';
                app.RunTime = 0;
                app.RunTimesSpinner.Value = 0;

                app.MTConditioningPulseSpinner.Visible = 'off';
                app.MTConditioningPulseSpinnerLabel.Visible = 'off';

                app.MTTestPulseSpinner.Visible = 'off';
                app.MTTestPulseSpinnerLabel.Visible = 'off';
                app.TestPulse = 0;
                app.MTTestPulseSpinner.Value = 0;

                app.PulseTypeName = 'Theta Burst';

                
            else % Paired Pulse
                % Visible Options
                app.MTSpinner.Visible = 'off';
                app.MTSpinnerLabel.Visible = 'off';
                app.MTConditioningPulseSpinner.Visible = 'on';
                app.MTConditioningPulseSpinnerLabel.Visible = 'on';
                app.MTConditioningPulseSpinner.Value = app.MTSpinner.Value;
                app.MTConditioningPulseSpinner.Position = [171,524,123,27];
                app.MTConditioningPulseSpinnerLabel.Position = [101,524,57,27];

                app.MTTestPulseSpinner.Visible = 'on';
                app.MTTestPulseSpinnerLabel.Visible = 'on';
                app.MTTestPulseSpinner.Position = [171,464,121,27];
                app.MTTestPulseSpinnerLabel.Position = [21,464,141,30];
                app.TestPulse = 0;
                app.MTTestPulseSpinner.Value = 0;

                app.NumberofTrialsSpinner.Visible = 'on';
                app.NumberofTrialsSpinnerLabel.Visible = 'on';
                app.NumberofTrialsSpinner.Position = [171,393,120,27];
                app.NumberofTrialsSpinnerLabel.Position = [11,393,145,49];
                app.NumberofTrials = 0;
                app.NumberofTrialsSpinner.Value = 0;

                app.InterPulseIntervalmsSpinner.Visible = 'on';
                app.InterPulseIntervalmsSpinnerLabel.Visible = 'on';
                app.InterPulseIntervalmsSpinner.Position = [171,325,121,27];
                app.InterPulseIntervalmsSpinnerLabel.Position = [11,325,144,47];
                app.IPI = 0;
                app.InterPulseIntervalmsSpinner.Value = 0;

                app.InterTrialIntervalsSpinner.Visible = 'on';
                app.InterTrialIntervalsSpinnerLabel.Visible = 'on';
                app.InterTrialIntervalsSpinner.Position = [171,250,121,27];
                app.InterTrialIntervalsSpinnerLabel.Position = [11,250,144,47];
                app.ITI = 0;
                app.InterTrialIntervalsSpinner.Value = 0;

                app.Image.Visible = 'off';
                app.OrLabel.Visible = 'off';

                % Not Visbible Options
                app.FrequencyofPulsesHzSpinner.Visible = 'off';
                app.FrequencyofPulsesHzSpinnerLabel.Visible = 'off';
                app.FrequencyofPulses = 0;
                app.FrequencyofPulsesHzSpinner.Value = 0;

                app.NumberofPulsesSpinner.Visible = 'off';
                app.NumberofPulsesLabel.Visible = 'off';
                app.NumberofPulsesperBurst = 0;
                app.NumberofPulsesSpinner.Value = 0;

                app.RunTimesSpinner.Visible = 'off';
                app.RunTimesSpinnerLabel.Visible = 'off';
                app.RunTime = 0;
                app.RunTimesSpinner.Value = 0;

                app.PulseTypeName = 'Paired Pulse';
            end            
        end

        % Value changed function: AdvancedOptionsCheckBox
        function AdvancedOptionsCheckBoxValueChanged(app, event)
            % If advanced options are checked, changes the visible options
            if app.AdvancedOptions == 0 % Checked
                app.CoilChargeDelaymsSpinner.Visible = 'on';
                app.CoilChargeDelaymsSpinnerLabel.Visible = 'on';
                app.AdvancedOptions = 1;
            else % Not Checked
                app.CoilChargeDelaymsSpinner.Visible = 'off';
                app.CoilChargeDelaymsSpinnerLabel.Visible = 'off';
                app.AdvancedOptions = 0;
            end            
        end

        % Value changed function: MTSpinner
        function MTSpinnerValueChanged(app, event)
            % Save variable to global variable and alter it to a cell array
            app.MT = app.MTSpinner.Value;
            app.MTConditioningPulseSpinner.Value = app.MTSpinner.Value; % Conditioning pulse is the same as %MT
            app.MT = num2cell(app.MT);     
        end

        % Value changed function: NumberofTrialsSpinner
        function NumberofTrialsSpinnerValueChanged(app, event)
             % Save variable to global variable and alter it to a cell array
             app.NumberofTrials = app.NumberofTrialsSpinner.Value;
             app.NumberofTrials = num2cell(app.NumberofTrials);            
        end

        % Value changed function: NumberofPulsesSpinner
        function NumberofPulsesSpinnerValueChanged(app, event)
            % Save variable to global variable and alter it to a cell array
            app.NumberofPulsesperBurst = app.NumberofPulsesSpinner.Value;
            app.NumberofPulsesperBurst = num2cell(app.NumberofPulsesperBurst);

            % If number of pulses is greater than 0, set run time to zero
            if app.NumberofPulsesSpinner.Value > 0
                app.RunTimesSpinner.Value = 0;
                app.RunTime = app.RunTimesSpinner.Value;
                app.RunTime = num2cell(app.RunTime);
            end
        end

        % Value changed function: RunTimesSpinner
        function RunTimesSpinnerValueChanged(app, event)
            % Save variable to global variable and alter it to a cell array
            app.RunTime = app.RunTimesSpinner.Value;
            app.RunTime = num2cell(app.RunTime);

            % If run time is greater than zero, number of pulses
            % (continuous) is set to 0
            if app.RunTimesSpinner.Value > 0
                app.NumberofPulsesSpinner.Value = 0;
                app.NumberofPulsesperBurst = app.NumberofPulsesSpinner.Value;
                app.NumberofPulsesperBurst = num2cell(app.NumberofPulsesperBurst);
            end
        end

        % Value changed function: InterPulseIntervalmsSpinner
        function InterPulseIntervalmsSpinnerValueChanged(app, event)
            % Save variable to global variable and alter it to a cell array
            app.IPI = app.InterPulseIntervalmsSpinner.Value;
            app.IPI = num2cell(app.IPI);     
        end

        % Value changed function: InterTrialIntervalsSpinner
        function InterTrialIntervalsSpinnerValueChanged(app, event)
            % Save variable to global variable and alter it to a cell array
            app.ITI = app.InterTrialIntervalsSpinner.Value;
            app.ITI = num2cell(app.ITI);            
        end

        % Value changed function: FrequencyofPulsesHzSpinner
        function FrequencyofPulsesHzSpinnerValueChanged(app, event)
            % Save variable to global variable and alter it to a cell array
            app.FrequencyofPulses = app.FrequencyofPulsesHzSpinner.Value;
            app.FrequencyofPulses= num2cell(app.FrequencyofPulses);          
        end

        % Value changed function: CoilChargeDelaymsSpinner
        function CoilChargeDelaymsSpinnerValueChanged(app, event)
            % Save variable to global variable and alter it to a cell array
            app.CoilChargeDelay = app.CoilChargeDelaymsSpinner.Value;
            app.CoilChargeDelay = num2cell(app.CoilChargeDelay);           
        end

        % Value changed function: MTConditioningPulseSpinner
        function MTConditioningPulseSpinnerValueChanged(app, event)
            % Save variable to global variable and alter it to a cell array
            app.MT = app.MTConditioningPulseSpinner.Value;
            app.MTSpinner.Value = app.MTConditioningPulseSpinner.Value; % Conditioning pulse is the same as %MT
            app.MT = num2cell(app.MT);
        end

        % Value changed function: MTTestPulseSpinner
        function MTTestPulseSpinnerValueChanged(app, event)
            % Save variable to global variable and alter it to a cell array
            app.TestPulse = app.MTTestPulseSpinner.Value;
            app.TestPulse = num2cell(app.TestPulse);
        end

        % Button pushed function: AddNextAutomationStepButton
        function AddNextAutomationStepButtonPushed(app, event)
            % Generates a table with the appropiate data every time the
            % AddNextAutomationStep Button is pushed

            % Increase step number by 1
            app.StepNumber = app.StepNumber + 1;
            % New row data
            AutomationData = [app.TableGroup, app.RandomorBlock, app.StepNumber, app.PulseTypeName, app.MT, app.NumberofTrials, app.NumberofPulsesperBurst, app.IPI, app.ITI, app.FrequencyofPulses, app.RunTime, app.TestPulse, app.CoilChargeDelay]; % Create data table
            % Adds data to table
            app.UITable.Data = [app.UITable.Data; AutomationData];
        end

        % Button pushed function: AddStepAboveButton
        function AddStepAboveButtonPushed(app, event)
            % Increase step number by 1
            app.StepNumber = app.StepNumber + 1;
            % Get grouping and random or blocked from selected cell
            AddStepGroup = app.UITable.Data(app.SelectedCell,1);
            AddStepRandom = app.UITable.Data(app.SelectedCell,2);
            
            % New row data
            InsertedRow = [AddStepGroup, AddStepRandom, app.StepNumber, app.PulseTypeName, app.MT, app.NumberofTrials, app.NumberofPulsesperBurst, app.IPI, app.ITI, app.FrequencyofPulses, app.RunTime, app.TestPulse, app.CoilChargeDelay];
            % Rows before the new row
            BeforeRows = [app.UITable.Data(1:app.SelectedCell-1,:)];
            % Rows after the new row
            AfterRows = [app.UITable.Data(app.SelectedCell:end,:)];
            % Add data to table
            app.UITable.Data = [BeforeRows; InsertedRow; AfterRows];
        end

        % Button pushed function: DeleteCurrentStepButton
        function DeleteCurrentStepButtonPushed(app, event)
            % Rows before deleted row
            BeforeRows = [app.UITable.Data(1:app.SelectedCell-1,:)];
            % Rows after deleted row
            AfterRows = [app.UITable.Data(app.SelectedCell+1:end,:)];
            % Delete row from table
            app.UITable.Data = [BeforeRows; AfterRows];
        end

        % Button pushed function: SaveCurrentAutomationtoExcelButton
        function SaveCurrentAutomationtoExcelButtonPushed(app, event)
            % Save data
            data = app.UITable.Data;
            filename = app.FileName;
            appDir = fileparts(mfilename('fullpath'));
            loc = [fileparts(appDir), '/sequences/'];
            if ~isfolder(loc)
                mkdir(loc)
            end
            filename = [loc, filename, '.xlsx'];
            writecell(data,filename);
            disp(['sequence saved to ', loc])
        end

        % Value changed function: FileNametoSaveDatatoNewFileEditField
        function FileNametoSaveDatatoNewFileEditFieldValueChanged(app, event)
            % File name if the a new file is created
            app.FileName = app.FileNametoSaveDatatoNewFileEditField.Value;            
        end

        % Button pushed function: AddNewGroupingButton
        function AddNewGroupingButtonPushed(app, event)
            % If another grouping is added, increase group number by 1
            app.TableGroup = app.TableGroup + 1;          
        end

        % Button pushed function: DuplicateGroupingButton
        function DuplicateGroupingButtonPushed(app, event)
            % New grouping is one more than previous last
            app.TableGroup = app.TableGroup + 1;

            % Group to duplicate label
            PreviousGroup = app.UITable.Data(app.SelectedCell,1);
            PreviousGroup = cell2mat(PreviousGroup);

            % Start with a blank matrix
            DuplicatedGroup = [];

            % Duplicate the steps in the grouping
            for i = 1:size(app.UITable.Data,1)
                if cell2mat(app.UITable.Data(i,1)) == PreviousGroup
                    DuplicatedGroup = [DuplicatedGroup; app.UITable.Data(i,:)];
                end
            end
      
            % Change the grouping number
            for i = 1:size(DuplicatedGroup,1)
                DuplicatedGroup{i,1} = app.TableGroup;
            end

            % Add the data to the table
            app.UITable.Data = [app.UITable.Data; DuplicatedGroup];
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            % Open Home Page
            TMS_StartUpPage

            % Close app
            delete(app)
        end

        % Value changed function: GroupingCategorizationDropDown
        function GroupingCategorizationDropDownValueChanged(app, event)
            % Change block or random variable
            RandomizedValue = app.GroupingCategorizationDropDown.Value;

            if RandomizedValue == 1 % Block
                app.RandomorBlock = 'Block';
            else
                app.RandomorBlock = 'Random';
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            % Saves the row number for the selected cell
            indices = event.Indices;
            app.SelectedCell = indices(1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ExperimentalAutomatorPanelLabel = uilabel(app.UIFigure);
            app.ExperimentalAutomatorPanelLabel.Text = 'Experimental Automator Panel';
            app.FrequencyofPulsesHzSpinnerLabel = uilabel(app.UIFigure);
            app.FrequencyofPulsesHzSpinnerLabel.Visible = 'off';
            app.FrequencyofPulsesHzSpinnerLabel.Text = 'Frequency of Pulses (Hz)';
            app.FrequencyofPulsesHzSpinner = uispinner(app.UIFigure);
            app.FrequencyofPulsesHzSpinner.Limits = [0 200];
            app.FrequencyofPulsesHzSpinner.RoundFractionalValues = 'on';
            app.FrequencyofPulsesHzSpinner.ValueChangedFcn = createCallbackFcn(app, @FrequencyofPulsesHzSpinnerValueChanged, true);
            app.FrequencyofPulsesHzSpinner.Visible = 'off';
            app.NumberofPulsesSpinner = uispinner(app.UIFigure);
            app.NumberofPulsesSpinner.Limits = [0 100];
            app.NumberofPulsesSpinner.RoundFractionalValues = 'on';
            app.NumberofPulsesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofPulsesSpinnerValueChanged, true);
            app.NumberofPulsesSpinner.Visible = 'off';
            app.NumberofPulsesLabel = uilabel(app.UIFigure);
            app.NumberofPulsesLabel.Visible = 'off';
            app.NumberofPulsesLabel.Text = 'Number of Pulses';
            app.InterTrialIntervalsSpinnerLabel = uilabel(app.UIFigure);
            app.InterTrialIntervalsSpinnerLabel.Text = 'Inter-Trial Interval (s)';
            app.InterTrialIntervalsSpinner = uispinner(app.UIFigure);
            app.InterTrialIntervalsSpinner.Limits = [0 600000];
            app.InterTrialIntervalsSpinner.ValueChangedFcn = createCallbackFcn(app, @InterTrialIntervalsSpinnerValueChanged, true);
            app.InterPulseIntervalmsSpinnerLabel = uilabel(app.UIFigure);
            app.InterPulseIntervalmsSpinnerLabel.Visible = 'off';
            app.InterPulseIntervalmsSpinnerLabel.Text = 'Inter-Pulse Interval (ms)';
            app.InterPulseIntervalmsSpinner = uispinner(app.UIFigure);
            app.InterPulseIntervalmsSpinner.Limits = [0 600000];
            app.InterPulseIntervalmsSpinner.RoundFractionalValues = 'on';
            app.InterPulseIntervalmsSpinner.ValueChangedFcn = createCallbackFcn(app, @InterPulseIntervalmsSpinnerValueChanged, true);
            app.InterPulseIntervalmsSpinner.Visible = 'off';
            app.AddNextAutomationStepButton = uibutton(app.UIFigure, 'push');
            app.AddNextAutomationStepButton.ButtonPushedFcn = createCallbackFcn(app, @AddNextAutomationStepButtonPushed, true);
            app.AddNextAutomationStepButton.Text = 'Add Next Automation Step';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Grouping'; 'Random or Block'; 'Step Number'; 'Pulse Type'; '%MT/%MT Conditioning Pulse'; 'Number of Trials'; 'Number of Pulses per Burst'; 'Inter Pulse Interval (ms)'; 'Inter Trial Interval (s)'; 'Frequency of Pulses (Hz)'; 'Run Time (s)'; '%MT Test Pulse'; 'Coil Charge Delay (ms)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true false true true true true true true true true true true];
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Multiselect = 'off';
            app.AddStepAboveButton = uibutton(app.UIFigure, 'push');
            app.AddStepAboveButton.ButtonPushedFcn = createCallbackFcn(app, @AddStepAboveButtonPushed, true);
            app.AddStepAboveButton.Text = 'Add Step Above';
            app.DeleteCurrentStepButton = uibutton(app.UIFigure, 'push');
            app.DeleteCurrentStepButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteCurrentStepButtonPushed, true);
            app.DeleteCurrentStepButton.Text = 'Delete Current Step';
            app.SaveCurrentAutomationtoExcelButton = uibutton(app.UIFigure, 'push');
            app.SaveCurrentAutomationtoExcelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentAutomationtoExcelButtonPushed, true);
            app.SaveCurrentAutomationtoExcelButton.Text = 'Save Current Automation to Excel';
            app.FileNametoSaveDatatoNewFileEditFieldLabel = uilabel(app.UIFigure);
            app.FileNametoSaveDatatoNewFileEditFieldLabel.Text = 'File Name to Save Data to New File';
            app.FileNametoSaveDatatoNewFileEditField = uieditfield(app.UIFigure, 'text');
            app.FileNametoSaveDatatoNewFileEditField.ValueChangedFcn = createCallbackFcn(app, @FileNametoSaveDatatoNewFileEditFieldValueChanged, true);
            app.PulseTypeDropDownLabel = uilabel(app.UIFigure);
            app.PulseTypeDropDownLabel.Text = 'Pulse Type';
            app.PulseTypeDropDown = uidropdown(app.UIFigure);
            app.PulseTypeDropDown.Items = {'Single Pulse', 'Continuous Pulses', 'Theta Burst', 'Paired Pulse'};
            app.PulseTypeDropDown.ItemsData = [1 2 3 4];
            app.PulseTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PulseTypeDropDownValueChanged, true);
            app.PulseTypeDropDown.Value = 1;
            app.AdvancedOptionsCheckBox = uicheckbox(app.UIFigure);
            app.AdvancedOptionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdvancedOptionsCheckBoxValueChanged, true);
            app.AdvancedOptionsCheckBox.Text = 'Advanced Options';
            app.NumberofTrialsSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofTrialsSpinnerLabel.Text = 'Number of Trials';
            app.NumberofTrialsSpinner = uispinner(app.UIFigure);
            app.NumberofTrialsSpinner.Limits = [0 1000];
            app.NumberofTrialsSpinner.RoundFractionalValues = 'on';
            app.NumberofTrialsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofTrialsSpinnerValueChanged, true);
            app.MTSpinnerLabel = uilabel(app.UIFigure);
            app.MTSpinnerLabel.Text = '%MT';
            app.MTSpinner = uispinner(app.UIFigure);
            app.MTSpinner.Limits = [0 200];
            app.MTSpinner.RoundFractionalValues = 'on';
            app.MTSpinner.ValueChangedFcn = createCallbackFcn(app, @MTSpinnerValueChanged, true);
            app.CoilChargeDelaymsSpinnerLabel = uilabel(app.UIFigure);
            app.CoilChargeDelaymsSpinnerLabel.Visible = 'off';
            app.CoilChargeDelaymsSpinnerLabel.Text = 'Coil Charge Delay (ms)';
            app.CoilChargeDelaymsSpinner = uispinner(app.UIFigure);
            app.CoilChargeDelaymsSpinner.Limits = [0 600000];
            app.CoilChargeDelaymsSpinner.RoundFractionalValues = 'on';
            app.CoilChargeDelaymsSpinner.ValueChangedFcn = createCallbackFcn(app, @CoilChargeDelaymsSpinnerValueChanged, true);
            app.CoilChargeDelaymsSpinner.Visible = 'off';
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.AddNewGroupingButton = uibutton(app.UIFigure, 'push');
            app.AddNewGroupingButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewGroupingButtonPushed, true);
            app.AddNewGroupingButton.Text = 'Add New Grouping';
            app.DuplicateGroupingButton = uibutton(app.UIFigure, 'push');
            app.DuplicateGroupingButton.ButtonPushedFcn = createCallbackFcn(app, @DuplicateGroupingButtonPushed, true);
            app.DuplicateGroupingButton.Text = 'Duplicate Grouping';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.GroupingCategorizationDropDownLabel = uilabel(app.UIFigure);
            app.GroupingCategorizationDropDownLabel.Text = 'Grouping Categorization';
            app.GroupingCategorizationDropDown = uidropdown(app.UIFigure);
            app.GroupingCategorizationDropDown.Items = {'Block', 'Random'};
            app.GroupingCategorizationDropDown.ItemsData = [1 2];
            app.GroupingCategorizationDropDown.ValueChangedFcn = createCallbackFcn(app, @GroupingCategorizationDropDownValueChanged, true);
            app.GroupingCategorizationDropDown.Value = 1;
            app.RunTimesSpinnerLabel = uilabel(app.UIFigure);
            app.RunTimesSpinnerLabel.Visible = 'off';
            app.RunTimesSpinnerLabel.Text = 'Run Time (s)';
            app.RunTimesSpinner = uispinner(app.UIFigure);
            app.RunTimesSpinner.Limits = [0 600000];
            app.RunTimesSpinner.RoundFractionalValues = 'on';
            app.RunTimesSpinner.ValueChangedFcn = createCallbackFcn(app, @RunTimesSpinnerValueChanged, true);
            app.RunTimesSpinner.Visible = 'off';
            app.OrLabel = uilabel(app.UIFigure);
            app.OrLabel.Visible = 'off';
            app.OrLabel.Text = 'Or';
            app.MTConditioningPulseSpinner = uispinner(app.UIFigure);
            app.MTConditioningPulseSpinner.Limits = [0 200];
            app.MTConditioningPulseSpinner.RoundFractionalValues = 'on';
            app.MTConditioningPulseSpinner.ValueChangedFcn = createCallbackFcn(app, @MTConditioningPulseSpinnerValueChanged, true);
            app.MTConditioningPulseSpinner.Visible = 'off';
            app.MTConditioningPulseSpinnerLabel = uilabel(app.UIFigure);
            app.MTConditioningPulseSpinnerLabel.Visible = 'off';
            app.MTConditioningPulseSpinnerLabel.Text = '%MT - Conditioning Pulse';
            app.MTTestPulseSpinner = uispinner(app.UIFigure);
            app.MTTestPulseSpinner.Limits = [0 200];
            app.MTTestPulseSpinner.RoundFractionalValues = 'on';
            app.MTTestPulseSpinner.ValueChangedFcn = createCallbackFcn(app, @MTTestPulseSpinnerValueChanged, true);
            app.MTTestPulseSpinner.Visible = 'off';
            app.MTTestPulseSpinnerLabel = uilabel(app.UIFigure);
            app.MTTestPulseSpinnerLabel.Visible = 'off';
            app.MTTestPulseSpinnerLabel.Text = '%MT - Test Pulse';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EditScreen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end