classdef ExperimentalAutomator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DropDown_2                      matlab.ui.control.DropDown
        DropDown                        matlab.ui.control.DropDown
        CarrierFrequencyHzSpinner       matlab.ui.control.Spinner
        CarrierFrequencyHzSpinnerLabel  matlab.ui.control.Label
        MTTestPulseSpinnerLabel         matlab.ui.control.Label
        MTTestPulseSpinner              matlab.ui.control.Spinner
        MTConditioningSpinnerLabel      matlab.ui.control.Label
        MTConditioningSpinner           matlab.ui.control.Spinner
        RunTimesSpinner                 matlab.ui.control.Spinner
        RunTimesSpinnerLabel            matlab.ui.control.Label
        GroupingCategorizationDropDown  matlab.ui.control.DropDown
        GroupingCategorizationDropDownLabel  matlab.ui.control.Label
        HomeButton                      matlab.ui.control.Button
        DuplicateGroupingButton         matlab.ui.control.Button
        AddNewGroupingButton            matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        Tree                            matlab.ui.container.Tree
        CoilChargeDelaymsSpinner        matlab.ui.control.Spinner
        CoilChargeDelaymsSpinnerLabel   matlab.ui.control.Label
        MTSpinner                       matlab.ui.control.Spinner
        MTSpinnerLabel                  matlab.ui.control.Label
        NumberofTrialsSpinner           matlab.ui.control.Spinner
        NumberofTrialsSpinnerLabel      matlab.ui.control.Label
        AdvancedOptionsCheckBox         matlab.ui.control.CheckBox
        PulseTypeDropDown               matlab.ui.control.DropDown
        PulseTypeDropDownLabel          matlab.ui.control.Label
        StepViewDropDown                matlab.ui.control.DropDown
        StepViewDropDownLabel           matlab.ui.control.Label
        FileNametoSaveDataEditField     matlab.ui.control.EditField
        FileNametoSaveDataEditFieldLabel  matlab.ui.control.Label
        SaveCurrentAutomationtoExcelButton  matlab.ui.control.Button
        DeleteCurrentStepButton         matlab.ui.control.Button
        AddStepAboveButton              matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        AddNextAutomationStepButton     matlab.ui.control.Button
        InterPulseIntervalmsSpinner     matlab.ui.control.Spinner
        InterPulseIntervalmsSpinnerLabel  matlab.ui.control.Label
        InterTrialIntervalsSpinner      matlab.ui.control.Spinner
        InterTrialIntervalsSpinnerLabel  matlab.ui.control.Label
        NumberofPulsesLabel             matlab.ui.control.Label
        NumberofPulsesperBurstSpinner   matlab.ui.control.Spinner
        FrequencyHzSpinner              matlab.ui.control.Spinner
        FrequencyHzSpinnerLabel         matlab.ui.control.Label
        ExperimentDesignerLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        % Variables used within multiple functions (app.Variable throughout
        % protocol)

        % Variables within the table
        IPI
        ITI
        NumberofPulsesperBurst
        NumberofTrials
        FrequencyofPulses
        MT
        CoilChargeDelay
        RunTime
        TestPulse
        StepNumber
        PulseTypeName
        GroupingNumber
        RandomorBlock

        % Nodes selected from tree
        SelectedNodes
        ParentNode % Grouping

        % Name of the file to save to
        FileName

        % Advanced Options varibales (if checked or not)
        AdvancedOptions

        % Overall grouping
        Grouping % Grouping name
        TreeName % Name for tree

        % Pulse type (single, ect) - number assigned to each pulse
        PulseType

        %Run type (for rTMS) count pulses or time 
        RunType
    end  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            appDir = fileparts(mfilename('fullpath'));
            disp(appDir)
            cd(appDir)
            % Starting variables (until changed)
            app.IPI = 0;
            app.ITI = 0;
            app.NumberofPulsesperBurst = 0;
            app.NumberofTrials = 0;
            app.FrequencyofPulses = 0;
            app.MT = 0;
            app.CoilChargeDelay = 0;
            app.RunTime = 0;
            app.TestPulse = 0;

            app.PulseType = 1;
            app.PulseTypeName = 'Single';
            app.StepNumber = 0; 

            app.RandomorBlock = 'Block';
            app.SelectedNodes = 0;

            app.FileName = 'TMS Experimental Automation';

            % Advanced options start off 
            app.AdvancedOptions = 0; 

            %default count pulses 
            app.RunType = '1';
            
            % Tree starts with one group called 'Grouping 1'
            app.TreeName = app.Tree;
            app.GroupingNumber = 1;
            GroupingLabel = "Grouping " + num2str(app.GroupingNumber); 
            app.Grouping = uitreenode(app.TreeName,"Text",GroupingLabel);
        end

        % Value changed function: PulseTypeDropDown
        function PulseTypeDropDownValueChanged(app, event)
            app.PulseType = app.PulseTypeDropDown.Value;

            % Depending on the pulse type chosen in the drop down, it
            % changes the parameter options, locations, and resets all
            % parameters except %MT each time

            if app.PulseType == 1 % Single Pulse
                % Visible Options
                app.MTSpinner.Visible = 'on';
                app.MTSpinnerLabel.Visible = 'on';
                app.MTSpinner.Position = [171,524,123,27];
                app.MTSpinnerLabel.Position = [101,524,57,27];

                app.NumberofTrialsSpinner.Visible = 'on';
                app.NumberofTrialsSpinnerLabel.Visible = 'on';
                app.NumberofTrialsSpinner.Position = [171,464,121,27];
                app.NumberofTrialsSpinnerLabel.Position = [21,464,141,30];
                app.NumberofTrials = 0;
                app.NumberofTrialsSpinner.Value = 0;

                app.InterTrialIntervalsSpinner.Visible = 'on';
                app.InterTrialIntervalsSpinnerLabel.Visible = 'on';
                app.InterTrialIntervalsSpinner.Position = [171,393,121,27];
                app.InterTrialIntervalsSpinnerLabel.Position = [22,394,146,48];
                app.ITI = 0;
                app.InterTrialIntervalsSpinner.Value = 0;

    
                % Not Visible Options
                app.InterPulseIntervalmsSpinner.Visible = 'off';
                app.InterPulseIntervalmsSpinnerLabel.Visible = 'off';
                app.IPI = 0;
                app.InterPulseIntervalmsSpinner.Value = 0;

                app.NumberofPulsesperBurstSpinner.Visible = 'off';
                app.NumberofPulsesLabel.Visible = 'off';
                app.NumberofPulsesperBurst = 0;
                app.NumberofPulsesperBurstSpinner.Value = 0;

                app.FrequencyHzSpinner.Visible = 'off';
                app.FrequencyHzSpinnerLabel.Visible = 'off';
                app.FrequencyofPulses = 0;
                app.FrequencyHzSpinner.Value = 0;

                app.RunTimesSpinner.Visible = 'off';
                app.RunTimesSpinnerLabel.Visible = 'off';
                app.RunTime = 0;
                app.RunTimesSpinner.Value = 0;

                app.MTConditioningSpinner.Visible = 'off';
                app.MTConditioningSpinnerLabel.Visible = 'off';

                app.MTTestPulseSpinner.Visible = 'off';
                app.MTTestPulseSpinnerLabel.Visible = 'off';
                app.TestPulse = 0;
                app.MTTestPulseSpinner.Value = 0;

                app.CarrierFrequencyHzSpinner.Visible = 'off';
                app.CarrierFrequencyHzSpinnerLabel.Visible = 'off';
                app.CarrierFrequencyHzSpinner.Position = [171,464,121,27];
                app.CarrierFrequencyHzSpinnerLabel.Position = [21,464,141,30];
                app.NumberofTrials = 0;
                app.CarrierFrequencyHzSpinner.Value = 0;

                app.DropDown.Visible = 'off';

                app.PulseTypeName = 'Single';

           elseif app.PulseType == 2  % Paired Pulse
                % Visible Options
                app.MTSpinner.Visible = 'off';
                app.MTSpinnerLabel.Visible = 'off';
                app.MTConditioningSpinner.Visible = 'on';
                app.MTConditioningSpinnerLabel.Visible = 'on';
                app.MTConditioningSpinner.Value = app.MTSpinner.Value;
                app.MTConditioningSpinner.Position = [221,524,70,27];
                app.MTConditioningSpinnerLabel.Position = [5,524,210,30];

                app.MTTestPulseSpinner.Visible = 'on';
                app.MTTestPulseSpinnerLabel.Visible = 'on';
                app.MTTestPulseSpinner.Position = [221,454,70,27];
                app.MTTestPulseSpinnerLabel.Position = [70,454,141,27];
                app.TestPulse = 0;
                app.MTTestPulseSpinner.Value = 0;

                app.NumberofTrialsSpinner.Visible = 'on';
                app.NumberofTrialsSpinnerLabel.Visible = 'on';
                app.NumberofTrialsSpinner.Position = [171,393,120,27];
                app.NumberofTrialsSpinnerLabel.Position = [11,385,145,49];
                app.NumberofTrials = 0;
                app.NumberofTrialsSpinner.Value = 0;

                app.InterPulseIntervalmsSpinner.Visible = 'on';
                app.InterPulseIntervalmsSpinnerLabel.Visible = 'on';
                app.InterPulseIntervalmsSpinner.Position = [171,325,121,27];
                app.InterPulseIntervalmsSpinnerLabel.Position = [11,325,144,47];
                app.IPI = 0;
                app.InterPulseIntervalmsSpinner.Value = 0;

                app.InterTrialIntervalsSpinner.Visible = 'on';
                app.InterTrialIntervalsSpinnerLabel.Visible = 'on';
                app.InterTrialIntervalsSpinner.Position = [171,250,121,27];
                app.InterTrialIntervalsSpinnerLabel.Position = [11,250,144,47];
                app.ITI = 0;
                app.InterTrialIntervalsSpinner.Value = 0;

                app.Image.Visible = 'off';

                % Not Visbible Options
                app.FrequencyHzSpinner.Visible = 'off';
                app.FrequencyHzSpinnerLabel.Visible = 'off';
                app.FrequencyofPulses = 0;
                app.FrequencyHzSpinner.Value = 0;

                app.NumberofPulsesperBurstSpinner.Visible = 'off';
                app.NumberofPulsesLabel.Visible = 'off';
                app.NumberofPulsesperBurst = 0;
                app.NumberofPulsesperBurstSpinner.Value = 0;

                app.RunTimesSpinner.Visible = 'off';
                app.RunTimesSpinnerLabel.Visible = 'off';
                app.RunTime = 0;
                app.RunTimesSpinner.Value = 0;

                app.CarrierFrequencyHzSpinner.Visible = 'off';
                app.CarrierFrequencyHzSpinnerLabel.Visible = 'off';
                app.CarrierFrequencyHzSpinner.Position = [171,464,121,27];
                app.CarrierFrequencyHzSpinnerLabel.Position = [21,464,141,30];
                app.NumberofTrials = 0;
                app.CarrierFrequencyHzSpinner.Value = 0;

                app.DropDown.Visible = 'off';

                app.PulseTypeName = 'Paired Pulse';

            elseif app.PulseType == 3 % Continuous Pulses
                % Visible Options
                app.MTSpinner.Visible = 'on';
                app.MTSpinnerLabel.Visible = 'on';
                app.MTSpinner.Position = [221,524,70,27];
                app.MTSpinnerLabel.Position = [121,524,57,27];

                app.DropDown.Visible = 'on';
                app.DropDown.Position = [20,464,180,30];

                if strcmp(app.RunType, '1') %continous rTMS for [count] pulses
                    app.NumberofPulsesperBurstSpinner.Visible = 'on';
                    app.NumberofPulsesLabel.Visible = 'off';
                    app.NumberofPulsesperBurstSpinner.Position = [221,464,70,27];
                    app.NumberofPulsesLabel.Position = [21,464,141,30];
                    app.NumberofPulsesperBurst = 0;
                    app.NumberofPulsesperBurstSpinner.Value = 0;

                    app.RunTimesSpinner.Visible = 'off';
                    app.RunTimesSpinnerLabel.Visible = 'off';
                    app.RunTimesSpinner.Position = [171,393,120,27];
                    app.RunTimesSpinnerLabel.Position = [11,383,145,49];
                    app.RunTime = 0;
                    app.RunTimesSpinner.Value = 0;
                elseif strcmp(app.RunType, '2')  %continous rTMS for [time] seconds
                    app.NumberofPulsesperBurstSpinner.Visible = 'off';
                    app.NumberofPulsesLabel.Visible = 'off';
                    app.NumberofPulsesperBurstSpinner.Position = [171,464,121,27];
                    app.NumberofPulsesLabel.Position = [21,464,141,30];
                    app.NumberofPulsesperBurst = 0;
                    app.NumberofPulsesperBurstSpinner.Value = 0;

                    app.RunTimesSpinner.Visible = 'on';
                    app.RunTimesSpinnerLabel.Visible = 'off';
                    app.RunTimesSpinner.Position = [221,464,70,27];
                    app.RunTimesSpinnerLabel.Position = [11,383,145,49];
                    app.RunTime = 0;
                    app.RunTimesSpinner.Value = 0;
                end

                app.FrequencyHzSpinner.Visible = 'on';
                app.FrequencyHzSpinnerLabel.Visible = 'on';
                app.FrequencyHzSpinner.Position = [171,325,120,27];
                app.FrequencyHzSpinnerLabel.Position = [17,325,150,27];
                app.FrequencyofPulses = 0;
                app.FrequencyHzSpinner.Value = 0;

                app.Image.Visible = 'off';

                % Not Visible Options
                app.InterPulseIntervalmsSpinner.Visible = 'off';
                app.InterPulseIntervalmsSpinnerLabel.Visible = 'off';
                app.IPI = 0;
                app.InterPulseIntervalmsSpinner.Value = 0;

                app.InterTrialIntervalsSpinner.Visible = 'off';
                app.InterTrialIntervalsSpinnerLabel.Visible = 'off';
                app.ITI = 0;
                app.InterTrialIntervalsSpinner.Value = 0;

                app.NumberofTrialsSpinner.Visible = 'off';
                app.NumberofTrialsSpinnerLabel.Visible = 'off';
                app.NumberofTrials = 0;
                app.NumberofTrialsSpinner.Value = 0;

                app.MTConditioningSpinner.Visible = 'off';
                app.MTConditioningSpinnerLabel.Visible = 'off';

                app.MTTestPulseSpinner.Visible = 'off';
                app.MTTestPulseSpinnerLabel.Visible = 'off';
                app.TestPulse = 0;
                app.MTTestPulseSpinner.Value = 0;

                app.CarrierFrequencyHzSpinner.Visible = 'off';
                app.CarrierFrequencyHzSpinnerLabel.Visible = 'off';
                app.CarrierFrequencyHzSpinner.Position = [171,464,121,27];
                app.CarrierFrequencyHzSpinnerLabel.Position = [21,464,141,30];
                app.NumberofTrials = 0;
                app.CarrierFrequencyHzSpinner.Value = 0;

                app.PulseTypeName = 'Continuous';


            else % Theta Burst
                % Visible Options
                app.MTSpinner.Visible = 'on';
                app.MTSpinnerLabel.Visible = 'on';
                app.MTSpinner.Position = [171,524,123,27];
                app.MTSpinnerLabel.Position = [101,524,57,27];

                %app.NumberofPulsesperBurstSpinner.Visible = 'on';
                %app.NumberOoPulsesperBurstLabel.Visible = 'on';

                app.NumberofTrialsSpinner.Visible = 'off';
                app.NumberofTrialsSpinnerLabel.Visible = 'off';
                app.NumberofTrialsSpinner.Position = [171,464,121,27];
                app.NumberofTrialsSpinnerLabel.Position = [21,464,141,30];
                app.NumberofTrials = 0;
                app.NumberofTrialsSpinner.Value = 0;

                app.CarrierFrequencyHzSpinner.Visible = 'on';
                app.CarrierFrequencyHzSpinnerLabel.Visible = 'on';
                app.CarrierFrequencyHzSpinner.Position = [171,464,121,27];
                app.CarrierFrequencyHzSpinnerLabel.Position = [21,464,141,30];
                app.NumberofTrials = 0;
                app.CarrierFrequencyHzSpinner.Value = 0;

                app.InterPulseIntervalmsSpinner.Visible = 'on';
                app.InterPulseIntervalmsSpinnerLabel.Visible = 'on';
                app.InterPulseIntervalmsSpinner.Position = [171,393,120,27];
                app.InterPulseIntervalmsSpinnerLabel.Position = [11,393,145,49];
                app.IPI = 0;
                app.InterPulseIntervalmsSpinner.Value = 0;

                app.NumberofPulsesperBurstSpinner.Visible = 'on';
                app.NumberofPulsesLabel.Visible = 'on';
                app.NumberofPulsesperBurstSpinner.Position = [171,325,120,27];
                app.NumberofPulsesLabel.Position = [15,325,146,48];
                app.NumberofPulsesperBurst = 0;
                app.NumberofPulsesperBurstSpinner.Value = 0;

                app.Image.Visible = 'off';

                % Not Visbible Options
                app.FrequencyHzSpinner.Visible = 'off';
                app.FrequencyHzSpinnerLabel.Visible = 'off';
                app.FrequencyofPulses = 0;
                app.FrequencyHzSpinner.Value = 0;

                app.InterTrialIntervalsSpinner.Visible = 'off';
                app.InterTrialIntervalsSpinnerLabel.Visible = 'off';
                app.ITI = 0;
                app.InterTrialIntervalsSpinner.Value = 0;

                app.RunTimesSpinner.Visible = 'off';
                app.RunTimesSpinnerLabel.Visible = 'off';
                app.RunTime = 0;
                app.RunTimesSpinner.Value = 0;

                app.MTConditioningSpinner.Visible = 'off';
                app.MTConditioningSpinnerLabel.Visible = 'off';

                app.MTTestPulseSpinner.Visible = 'off';
                app.MTTestPulseSpinnerLabel.Visible = 'off';
                app.TestPulse = 0;
                app.MTTestPulseSpinner.Value = 0;

                app.DropDown.Visible = 'off';

                app.PulseTypeName = 'Theta Burst';

            end            
        end

        % Value changed function: AdvancedOptionsCheckBox
        function AdvancedOptionsCheckBoxValueChanged(app, event)
            % If advanced options are checked, changes the visible options
            if app.AdvancedOptions == 0 % Checked
                app.CoilChargeDelaymsSpinner.Visible = 'on';
                app.CoilChargeDelaymsSpinnerLabel.Visible = 'on';
                app.AdvancedOptions = 1;
            else % Not Checked
                app.CoilChargeDelaymsSpinner.Visible = 'off';
                app.CoilChargeDelaymsSpinnerLabel.Visible = 'off';
                app.AdvancedOptions = 0;
            end            
        end

        % Value changed function: MTSpinner
        function MTSpinnerValueChanged(app, event)
            % Save variable to global variable and alter it to a cell array
            app.MT = app.MTSpinner.Value;
            app.MTConditioningSpinner.Value = app.MTSpinner.Value; % Conditioning pulse is the same as %MT
            app.MT = num2cell(app.MT);     
        end

        % Value changed function: NumberofTrialsSpinner
        function NumberofTrialsSpinnerValueChanged(app, event)
             % Save variable to global variable and alter it to a cell array
             app.NumberofTrials = app.NumberofTrialsSpinner.Value;
             app.NumberofTrials = num2cell(app.NumberofTrials);            
        end

        % Value changed function: NumberofPulsesperBurstSpinner
        function NumberofPulsesperBurstSpinnerValueChanged(app, event)
            % Save variable to global variable and alter it to a cell array
            app.NumberofPulsesperBurst = app.NumberofPulsesperBurstSpinner.Value;
            app.NumberofPulsesperBurst = num2cell(app.NumberofPulsesperBurst);

            % If number of pulses is greater than 0, set run time to zero
            if app.NumberofPulsesperBurstSpinner.Value > 0
                app.RunTimesSpinner.Value = 0;
                app.RunTime = app.RunTimesSpinner.Value;
                app.RunTime = num2cell(app.RunTime);
            end
        end

        % Value changed function: RunTimesSpinner
        function RunTimesSpinnerValueChanged(app, event)
            % Save variable to global variable and alter it to a cell array
            app.RunTime = app.RunTimesSpinner.Value;
            app.RunTime = num2cell(app.RunTime);

            % If run time is greater than zero, number of pulses
            % (continuous) is set to 0
            if app.RunTimesSpinner.Value > 0
                app.NumberofPulsesperBurstSpinner.Value = 0;
                app.NumberofPulsesperBurst = app.NumberofPulsesperBurstSpinner.Value;
                app.NumberofPulsesperBurst = num2cell(app.NumberofPulsesperBurst);
            end
        end

        % Value changed function: InterPulseIntervalmsSpinner
        function InterPulseIntervalmsSpinnerValueChanged(app, event)
            % Save variable to global variable and alter it to a cell array
            app.IPI = app.InterPulseIntervalmsSpinner.Value;
            app.IPI = num2cell(app.IPI);     
        end

        % Value changed function: InterTrialIntervalsSpinner
        function InterTrialIntervalsSpinnerValueChanged(app, event)
            % Save variable to global variable and alter it to a cell array
            app.ITI = app.InterTrialIntervalsSpinner.Value;
            app.ITI = num2cell(app.ITI);            
        end

        % Value changed function: FrequencyHzSpinner
        function FrequencyHzSpinnerValueChanged(app, event)
            % Save variable to global variable and alter it to a cell array
            app.FrequencyofPulses = app.FrequencyHzSpinner.Value;
            app.FrequencyofPulses= num2cell(app.FrequencyofPulses);          
        end

        % Value changed function: CoilChargeDelaymsSpinner
        function CoilChargeDelaymsSpinnerValueChanged(app, event)
            % Save variable to global variable and alter it to a cell array
            app.CoilChargeDelay = app.CoilChargeDelaymsSpinner.Value;
            app.CoilChargeDelay = num2cell(app.CoilChargeDelay);           
        end

        % Value changed function: MTConditioningSpinner
        function MTConditioningSpinnerValueChanged(app, event)
            % Save variable to global variable and alter it to a cell array
            app.MT = app.MTConditioningSpinner.Value;
            app.MTSpinner.Value = app.MTConditioningSpinner.Value; % Conditioning pulse is the same at %MT
            app.MT = num2cell(app.MT);
        end

        % Value changed function: MTTestPulseSpinner
        function MTTestPulseSpinnerValueChanged(app, event)
            % Save variable to global variable and alter it to a cell array
            app.TestPulse = app.MTTestPulseSpinner.Value;
            app.TestPulse = num2cell(app.TestPulse);
        end

        % Button pushed function: AddNextAutomationStepButton
        function AddNextAutomationStepButtonPushed(app, event)
            % Generates a table row with the appropiate data every time the
            % AddNextAutomationStep Button is pushed

            app.StepNumber = app.StepNumber + 1; % Increase step number
            TableGroup = app.Grouping.Text; % Set grouping number
            AutomationData = [TableGroup, app.RandomorBlock, app.StepNumber, app.PulseTypeName, app.MT, app.NumberofTrials, app.NumberofPulsesperBurst, app.IPI, app.ITI, app.FrequencyofPulses, app.RunTime, app.TestPulse, app.CoilChargeDelay]; % Create data table
            app.UITable.Data = [app.UITable.Data; AutomationData]; % Adds data to table

            % Add step number to tree with name
            if app.PulseType == 1
                label = "Single Pulse " + num2str(app.StepNumber); 
            elseif app.PulseType == 2
                label = "Paired Pulse " + num2str(app.StepNumber); 
            elseif app.PulseType == 3
                label = "Continuous Pulses " + num2str(app.StepNumber); 
            else
                label = "Theta Burst " + num2str(app.StepNumber); 
            end
            uitreenode(app.Grouping,"Text",label);
            expand(app.TreeName)            

        end

        % Button pushed function: AddStepAboveButton
        function AddStepAboveButtonPushed(app, event)
            % Step number
            app.StepNumber = app.StepNumber + 1;

            % Add step the tree with label
            if app.PulseType == 1
                label = "Single Pulse " + num2str(app.StepNumber); 
            elseif app.PulseType == 2
                label = "Paired Pulse " + num2str(app.StepNumber); 
            elseif app.PulseType == 3
                label = "Continuous Pulses " + num2str(app.StepNumber); 
            else
                label = "Theta Burst " + num2str(app.StepNumber); 
            end

            % Grouping number is based off of the parent of the selected
            % tree
            AddStepGrouping = app.SelectedNodes.Parent;
            
            % Determines the number of groups before selected group
            for i = 1:length(app.Tree.Children)
                if app.Tree.Children(i) == AddStepGrouping
                    GroupNumberCounter = i;
                end
            end
            
            % Correct to the step above the chosen
            GroupNumberCounter = GroupNumberCounter - 1;

            % Starting counter
            BeforeGroupingSteps = 0;

            % Determines the total number of steps in the previous
            % groupings
            for i = 1:GroupNumberCounter
                BeforeGroupingSteps = BeforeGroupingSteps + length(app.Tree.Children(i).Children);
            end

            % Determines the totoal number of steps in the grouping before
            % the chosen step
            for i = 1:length(AddStepGrouping.Children)
                if AddStepGrouping.Children(i) == app.SelectedNodes
                    c1 = AddStepGrouping.Children(i);
                    WithinGroupingStep = i;
                end
            end

            % Determines the total number previous steps
            GroupingStep = BeforeGroupingSteps + WithinGroupingStep;

            % Adds the new step to the tree in the appropriate location
            uitreenode(AddStepGrouping,c1,"before","Text",label)
            
            % Gets the variable needed for the groupining the table
            TableGroup = AddStepGrouping.Text;

            % Random or not in the add step above based on the current step
            AfterRandom = app.UITable.Data(GroupingStep,2);
            
            % Inserts the new step into the table
            InsertedRow = [TableGroup, AfterRandom, app.StepNumber, app.PulseTypeName, app.MT, app.NumberofTrials, app.NumberofPulsesperBurst, app.IPI, app.ITI, app.FrequencyofPulses, app.RunTime, app.TestPulse, app.CoilChargeDelay]; % New row
            BeforeRows = [app.UITable.Data(1:GroupingStep-1,:)]; % Determines before rows
            AfterRows = [app.UITable.Data(GroupingStep:end,:)]; % Determines after rows
            app.UITable.Data = [BeforeRows; InsertedRow; AfterRows]; % Creates a new table
        end

        % Button pushed function: DeleteCurrentStepButton
        function DeleteCurrentStepButtonPushed(app, event)
            % Grouping the deleted step will be in based on the parents
            DeleteStepGrouping = app.SelectedNodes.Parent;

            % Number of groupings before the one the selected step is in
            for i = 1:length(app.Tree.Children)
                if app.Tree.Children(i) == DeleteStepGrouping
                    GroupNumberCounter = i;
                end
            end

            % Correcting variables
            GroupNumberCounter = GroupNumberCounter - 1;
            BeforeGroupingSteps = 0;

            % Number of steps in previous groupings
            for i = 1:GroupNumberCounter
                BeforeGroupingSteps = BeforeGroupingSteps + length(app.Tree.Children(i).Children);
            end
            
            % Determines the number of steps in grouping before selected
            for i = 1:length(DeleteStepGrouping.Children)
                if DeleteStepGrouping.Children(i) == app.SelectedNodes
                    WithinGroupingStep = i;
                end
            end

            % Total number of groups before the selected group
            GroupingStep = BeforeGroupingSteps + WithinGroupingStep;
            
            % Determines the steps before (rows of table) and after and
            % makes table without selected step
            BeforeRows = [app.UITable.Data(1:GroupingStep-1,:)];
            AfterRows = [app.UITable.Data(GroupingStep+1:end,:)];
            app.UITable.Data = [BeforeRows; AfterRows];

            % Deletes step in table
            delete(app.SelectedNodes)
        end

        % Button pushed function: SaveCurrentAutomationtoExcelButton
        function SaveCurrentAutomationtoExcelButtonPushed(app, event)
            % Saves the data to an excel file
            data = app.UITable.Data;             
            filename = app.FileName;
            appDir = fileparts(mfilename('fullpath'));
            loc = [fileparts(appDir), '/sequences/'];
            if ~isfolder(loc)
                mkdir(loc)
            end
            filename = [loc, filename, '.xlsx'];
            writecell(data,filename);
            disp(['sequence saved to ', loc])
        end

        % Value changed function: FileNametoSaveDataEditField
        function FileNametoSaveDataEditFieldValueChanged(app, event)
            % If file name is changed, the global variable is changed
            app.FileName = app.FileNametoSaveDataEditField.Value;            
        end

        % Value changed function: StepViewDropDown
        function StepViewDropDownValueChanged(app, event)
            % The options for editting are not available in table view
            if app.StepViewDropDown.Value == 1 % Tree is visible
                app.Tree.Visible = 'on';
                app.UITable.Visible = 'off';
                app.DeleteCurrentStepButton.Visible = 'on';
                app.AddStepAboveButton.Visible = 'on';
                app.AddNewGroupingButton.Visible = 'on';
                app.DuplicateGroupingButton.Visible = 'on';
            else % Table is visible
                app.Tree.Visible = 'off';
                app.UITable.Visible = 'on';
                app.DeleteCurrentStepButton.Visible = 'off';
                app.AddStepAboveButton.Visible = 'off';
                app.AddNewGroupingButton.Visible = 'off';
                app.DuplicateGroupingButton.Visible = 'off';
            end            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            % Selected nodes is set a global variable
            app.SelectedNodes = app.Tree.SelectedNodes;
        end

        % Button pushed function: AddNewGroupingButton
        function AddNewGroupingButtonPushed(app, event)
            % Group number is changed by one and a new group is added to
            % the tree
            app.GroupingNumber = app.GroupingNumber + 1;
            GroupingLabel = "Grouping " + num2str(app.GroupingNumber); 
            app.Grouping = uitreenode(app.TreeName,"Text",GroupingLabel);           
        end

        % Button pushed function: DuplicateGroupingButton
        function DuplicateGroupingButtonPushed(app, event)
            % Grouping number is grouping number plus one
            app.GroupingNumber = app.GroupingNumber + 1;
            GroupingLabel = "Grouping " + num2str(app.GroupingNumber); 
            app.Grouping = uitreenode(app.TreeName,"Text",GroupingLabel);

            % Takes a copy of each part of the group for the tree
            for i = 1:length(app.SelectedNodes.Children)
                ChildNode = app.SelectedNodes.Children(i);
                NewChildNode = copy(ChildNode);
                NewChildNode.Parent = app.Grouping;
                uitreenode(app.Grouping,NewChildNode);
            end

            % Above doubles each of the groupings and needs to be deleted 
            delete(app.Grouping.Children(2:2:end))

            % Determines the number of groups before the one to be copied
            for i = 1:length(app.Tree.Children)
                if app.Tree.Children(i) == app.SelectedNodes
                    GroupNumberCounter = i;
                end
            end

            % Corrects the variable to the steps before
            GroupNumberCounter = GroupNumberCounter - 1;
            BeforeGroupingSteps = 0;

            % Number of steps in previous groupings
            for i = 1:GroupNumberCounter
                BeforeGroupingSteps = BeforeGroupingSteps + length(app.Tree.Children(i).Children);
            end

            % Determines the starting step of the group we want to
            % duplicate
            StartingStep = BeforeGroupingSteps + 1;
            EndingStep = StartingStep + length(app.SelectedNodes.Children)-1;
            
            % Before rows = whole table, after = duplicate
            BeforeRows = [app.UITable.Data(1:end,:)];
            AfterRows = [app.UITable.Data(StartingStep:EndingStep,:)];

            % Grouping
            DuplicationGroup = app.Grouping.Text;
            
            % Changes the grouping number for all steps in duplicate
            for i = 1:size(AfterRows,1)
                AfterRows(i,1) = {DuplicationGroup};
            end

            % Adds to the table
            app.UITable.Data = [BeforeRows; AfterRows];
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            % Open home page
            TMS_StartUpPage

            % Close app
            delete(app)
        end

        % Value changed function: GroupingCategorizationDropDown
        function GroupingCategorizationDropDownValueChanged(app, event)
            % Random or block value
            RandomizedValue = app.GroupingCategorizationDropDown.Value;

            % Sets variable based on the random or block number
            if RandomizedValue == 1 % Block
                app.RandomorBlock = 'Block';
            else
                app.RandomorBlock = 'Random';
            end
            
            % Sets variable to cell
            RandomCell = {app.RandomorBlock};

            % Determines the length of the grouping to make random or block
            NodeLength = 0;
            if app.SelectedNodes ~= 0
                NodeLength = length(app.SelectedNodes.Children);
            end

            % If the node length is greater than 0 - then change the random
            % or block for the entire grouping
            if NodeLength > 0
                % Determine which grouping
                for i = 1:length(app.Tree.Children)
                    if app.Tree.Children(i) == app.SelectedNodes
                        GroupNumberCounter = i;
                    end
                end
    
                GroupNumberCounter = GroupNumberCounter - 1;
                BeforeGroupingSteps = 0;
    
                % Number of steps in previous groups
                for i = 1:GroupNumberCounter
                    BeforeGroupingSteps = BeforeGroupingSteps + length(app.Tree.Children(i).Children);
                end
                
                % Length of selected groupings
                StartingStep = BeforeGroupingSteps + 1;
                EndingStep = StartingStep + length(app.SelectedNodes.Children)-1;
    
                % Change block or random value for all of the steps in the
                % grouping
                for i = StartingStep:EndingStep
                    app.UITable.Data(i,2) = RandomCell;
                end
            end
        end

        % Value changed function: DropDown
        function CountOrTimeChanged(app, event)
            app.RunType = app.DropDown.Value;
            if strcmp(app.RunType, '1') %continous rTMS for [count] pulses
                    app.NumberofPulsesperBurstSpinner.Visible = 'on';
                    app.NumberofPulsesLabel.Visible = 'off';
                    app.NumberofPulsesperBurstSpinner.Position = [221,464,70,27];
                    app.NumberofPulsesLabel.Position = [21,464,141,30];
                    app.NumberofPulsesperBurst = 0;
                    app.NumberofPulsesperBurstSpinner.Value = 0;

                    app.RunTimesSpinner.Visible = 'off';
                    app.RunTimesSpinnerLabel.Visible = 'off';
                    app.RunTimesSpinner.Position = [171,393,121,27];
                    app.RunTimesSpinnerLabel.Position = [11,383,145,49];
                    app.RunTime = 0;
                    app.RunTimesSpinner.Value = 0;
             elseif strcmp(app.RunType, '2')  %continous rTMS for [time] seconds
                    app.NumberofPulsesperBurstSpinner.Visible = 'off';
                    app.NumberofPulsesLabel.Visible = 'off';
                    app.NumberofPulsesperBurstSpinner.Position = [171,464,121,27];
                    app.NumberofPulsesLabel.Position = [21,464,141,30];
                    app.NumberofPulsesperBurst = 0;
                    app.NumberofPulsesperBurstSpinner.Value = 0;

                    app.RunTimesSpinner.Visible = 'on';
                    app.RunTimesSpinnerLabel.Visible = 'off';
                    app.RunTimesSpinner.Position = [221,464,70,27];
                    app.RunTimesSpinnerLabel.Position = [11,383,145,49];
                    app.RunTime = 0;
                    app.RunTimesSpinner.Value = 0;
             end
            
        end

        % Value changed function: CarrierFrequencyHzSpinner
        function CarrierFreqSpinnerValueChanged(app, event)
            value = app.CarrierFrequencyHzSpinner.Value;
            
        end

        % Callback function: not associated with a component
        function NumBurstsValueChangedFcn(app, event)
            value = app.NumberofPulsesperBurstSpinner_2.Value;
            
        end

        % Callback function: not associated with a component
        function NumBursts(app, event)
            value = app.NumberofPulsesperBurstSpinner_2.Value;
            
        end

        % Value changed function: DropDown_2
        function NumTrainsOrRunTime(app, event)
            value = app.DropDown_2.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ExperimentDesignerLabel = uilabel(app.UIFigure);
            app.ExperimentDesignerLabel.Text = 'Experiment Designer';
            app.FrequencyHzSpinnerLabel = uilabel(app.UIFigure);
            app.FrequencyHzSpinnerLabel.Visible = 'off';
            app.FrequencyHzSpinnerLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzSpinner = uispinner(app.UIFigure);
            app.FrequencyHzSpinner.Limits = [0 200];
            app.FrequencyHzSpinner.RoundFractionalValues = 'on';
            app.FrequencyHzSpinner.ValueChangedFcn = createCallbackFcn(app, @FrequencyHzSpinnerValueChanged, true);
            app.FrequencyHzSpinner.Visible = 'off';
            app.NumberofPulsesperBurstSpinner = uispinner(app.UIFigure);
            app.NumberofPulsesperBurstSpinner.Limits = [0 9999];
            app.NumberofPulsesperBurstSpinner.RoundFractionalValues = 'on';
            app.NumberofPulsesperBurstSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofPulsesperBurstSpinnerValueChanged, true);
            app.NumberofPulsesperBurstSpinner.Visible = 'off';
            app.NumberofPulsesperBurstSpinner.Value = 3;
            app.NumberofPulsesLabel = uilabel(app.UIFigure);
            app.NumberofPulsesLabel.Tag = 'NumberofPulsesperBurstLabel';
            app.NumberofPulsesLabel.Visible = 'off';
            app.NumberofPulsesLabel.Text = 'Number of Pulses per Burst';
            app.InterTrialIntervalsSpinnerLabel = uilabel(app.UIFigure);
            app.InterTrialIntervalsSpinnerLabel.Text = 'Inter-Trial Interval (s)';
            app.InterTrialIntervalsSpinner = uispinner(app.UIFigure);
            app.InterTrialIntervalsSpinner.Limits = [0 600000];
            app.InterTrialIntervalsSpinner.ValueChangedFcn = createCallbackFcn(app, @InterTrialIntervalsSpinnerValueChanged, true);
            app.InterPulseIntervalmsSpinnerLabel = uilabel(app.UIFigure);
            app.InterPulseIntervalmsSpinnerLabel.Visible = 'off';
            app.InterPulseIntervalmsSpinnerLabel.Text = 'Inter-Pulse Interval (ms)';
            app.InterPulseIntervalmsSpinner = uispinner(app.UIFigure);
            app.InterPulseIntervalmsSpinner.Limits = [0 600000];
            app.InterPulseIntervalmsSpinner.RoundFractionalValues = 'on';
            app.InterPulseIntervalmsSpinner.ValueChangedFcn = createCallbackFcn(app, @InterPulseIntervalmsSpinnerValueChanged, true);
            app.InterPulseIntervalmsSpinner.Visible = 'off';
            app.AddNextAutomationStepButton = uibutton(app.UIFigure, 'push');
            app.AddNextAutomationStepButton.ButtonPushedFcn = createCallbackFcn(app, @AddNextAutomationStepButtonPushed, true);
            app.AddNextAutomationStepButton.Text = 'Add Next Automation Step';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Grouping'; 'Random or Block'; 'Step Number'; 'Pulse Type'; '%MT/%MT Conditioning Pulse'; 'Number of Trials'; 'Number of Pulses per Burst'; 'Inter Pulse Interval (ms)'; 'Inter Trial Interval (s)'; 'Frequency of Pulses (Hz)'; 'Run Time (s)'; '%MT Test Pulse'; 'Coil Charge Delay (ms)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false false true true true true true true true true];
            app.UITable.Multiselect = 'off';
            app.UITable.Visible = 'off';
            app.AddStepAboveButton = uibutton(app.UIFigure, 'push');
            app.AddStepAboveButton.ButtonPushedFcn = createCallbackFcn(app, @AddStepAboveButtonPushed, true);
            app.AddStepAboveButton.Text = 'Add Step Above';
            app.DeleteCurrentStepButton = uibutton(app.UIFigure, 'push');
            app.DeleteCurrentStepButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteCurrentStepButtonPushed, true);
            app.DeleteCurrentStepButton.Text = 'Delete Current Step';
            app.SaveCurrentAutomationtoExcelButton = uibutton(app.UIFigure, 'push');
            app.SaveCurrentAutomationtoExcelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentAutomationtoExcelButtonPushed, true);
            app.SaveCurrentAutomationtoExcelButton.Text = 'Save Current Automation to Excel';
            app.FileNametoSaveDataEditFieldLabel = uilabel(app.UIFigure);
            app.FileNametoSaveDataEditFieldLabel.Text = 'File Name to Save Data';
            app.FileNametoSaveDataEditField = uieditfield(app.UIFigure, 'text');
            app.FileNametoSaveDataEditField.ValueChangedFcn = createCallbackFcn(app, @FileNametoSaveDataEditFieldValueChanged, true);
            app.StepViewDropDownLabel = uilabel(app.UIFigure);
            app.StepViewDropDownLabel.Text = 'Step View';
            app.StepViewDropDown = uidropdown(app.UIFigure);
            app.StepViewDropDown.Items = {'List', 'Table'};
            app.StepViewDropDown.ItemsData = [1 2];
            app.StepViewDropDown.ValueChangedFcn = createCallbackFcn(app, @StepViewDropDownValueChanged, true);
            app.StepViewDropDown.Value = 1;
            app.PulseTypeDropDownLabel = uilabel(app.UIFigure);
            app.PulseTypeDropDownLabel.Text = 'Pulse Type';
            app.PulseTypeDropDown = uidropdown(app.UIFigure);
            app.PulseTypeDropDown.Items = {'Single Pulse', 'Paired Pulse', 'Continuous rTMS', 'Theta Burst rTMS'};
            app.PulseTypeDropDown.ItemsData = [1 2 3 4];
            app.PulseTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PulseTypeDropDownValueChanged, true);
            app.PulseTypeDropDown.Value = 1;
            app.AdvancedOptionsCheckBox = uicheckbox(app.UIFigure);
            app.AdvancedOptionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdvancedOptionsCheckBoxValueChanged, true);
            app.AdvancedOptionsCheckBox.Text = 'Advanced Options';
            app.NumberofTrialsSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofTrialsSpinnerLabel.Text = 'Number of Trials';
            app.NumberofTrialsSpinner = uispinner(app.UIFigure);
            app.NumberofTrialsSpinner.Limits = [0 1000];
            app.NumberofTrialsSpinner.RoundFractionalValues = 'on';
            app.NumberofTrialsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofTrialsSpinnerValueChanged, true);
            app.MTSpinnerLabel = uilabel(app.UIFigure);
            app.MTSpinnerLabel.Text = '%MT';
            app.MTSpinner = uispinner(app.UIFigure);
            app.MTSpinner.Limits = [0 200];
            app.MTSpinner.RoundFractionalValues = 'on';
            app.MTSpinner.ValueChangedFcn = createCallbackFcn(app, @MTSpinnerValueChanged, true);
            app.CoilChargeDelaymsSpinnerLabel = uilabel(app.UIFigure);
            app.CoilChargeDelaymsSpinnerLabel.Visible = 'off';
            app.CoilChargeDelaymsSpinnerLabel.Text = 'Coil Charge Delay (ms)';
            app.CoilChargeDelaymsSpinner = uispinner(app.UIFigure);
            app.CoilChargeDelaymsSpinner.Limits = [0 600000];
            app.CoilChargeDelaymsSpinner.RoundFractionalValues = 'on';
            app.CoilChargeDelaymsSpinner.ValueChangedFcn = createCallbackFcn(app, @CoilChargeDelaymsSpinnerValueChanged, true);
            app.CoilChargeDelaymsSpinner.Visible = 'off';
            app.Tree = uitree(app.UIFigure);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.AddNewGroupingButton = uibutton(app.UIFigure, 'push');
            app.AddNewGroupingButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewGroupingButtonPushed, true);
            app.AddNewGroupingButton.Text = 'Add New Grouping';
            app.DuplicateGroupingButton = uibutton(app.UIFigure, 'push');
            app.DuplicateGroupingButton.ButtonPushedFcn = createCallbackFcn(app, @DuplicateGroupingButtonPushed, true);
            app.DuplicateGroupingButton.Text = 'Duplicate Grouping';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.GroupingCategorizationDropDownLabel = uilabel(app.UIFigure);
            app.GroupingCategorizationDropDownLabel.Text = 'Grouping Categorization';
            app.GroupingCategorizationDropDown = uidropdown(app.UIFigure);
            app.GroupingCategorizationDropDown.Items = {'Block', 'Random'};
            app.GroupingCategorizationDropDown.ItemsData = [1 2];
            app.GroupingCategorizationDropDown.ValueChangedFcn = createCallbackFcn(app, @GroupingCategorizationDropDownValueChanged, true);
            app.GroupingCategorizationDropDown.Value = 1;
            app.RunTimesSpinnerLabel = uilabel(app.UIFigure);
            app.RunTimesSpinnerLabel.Visible = 'off';
            app.RunTimesSpinnerLabel.Text = 'Run Time (s)';
            app.RunTimesSpinner = uispinner(app.UIFigure);
            app.RunTimesSpinner.Limits = [0 600000];
            app.RunTimesSpinner.ValueChangedFcn = createCallbackFcn(app, @RunTimesSpinnerValueChanged, true);
            app.RunTimesSpinner.Visible = 'off';
            app.MTConditioningSpinner = uispinner(app.UIFigure);
            app.MTConditioningSpinner.Limits = [0 200];
            app.MTConditioningSpinner.RoundFractionalValues = 'on';
            app.MTConditioningSpinner.ValueChangedFcn = createCallbackFcn(app, @MTConditioningSpinnerValueChanged, true);
            app.MTConditioningSpinner.Visible = 'off';
            app.MTConditioningSpinnerLabel = uilabel(app.UIFigure);
            app.MTConditioningSpinnerLabel.Visible = 'off';
            app.MTConditioningSpinnerLabel.Text = '%MT - Conditioning ';
            app.MTTestPulseSpinner = uispinner(app.UIFigure);
            app.MTTestPulseSpinner.Limits = [0 200];
            app.MTTestPulseSpinner.RoundFractionalValues = 'on';
            app.MTTestPulseSpinner.ValueChangedFcn = createCallbackFcn(app, @MTTestPulseSpinnerValueChanged, true);
            app.MTTestPulseSpinner.Visible = 'off';
            app.MTTestPulseSpinnerLabel = uilabel(app.UIFigure);
            app.MTTestPulseSpinnerLabel.Visible = 'off';
            app.MTTestPulseSpinnerLabel.Text = '%MT - Test Pulse';
            app.CarrierFrequencyHzSpinnerLabel = uilabel(app.UIFigure);
            app.CarrierFrequencyHzSpinnerLabel.Visible = 'off';
            app.CarrierFrequencyHzSpinnerLabel.Text = 'Carrier Frequency (Hz)';
            app.CarrierFrequencyHzSpinner = uispinner(app.UIFigure);
            app.CarrierFrequencyHzSpinner.Limits = [0 1000];
            app.CarrierFrequencyHzSpinner.RoundFractionalValues = 'on';
            app.CarrierFrequencyHzSpinner.ValueChangedFcn = createCallbackFcn(app, @CarrierFreqSpinnerValueChanged, true);
            app.CarrierFrequencyHzSpinner.Visible = 'off';
            app.CarrierFrequencyHzSpinner.Value = 3;
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Number of Pulses', 'Run Time (s)'};
            app.DropDown.ItemsData = {'1', '2'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @CountOrTimeChanged, true);
            app.DropDown.Visible = 'off';
            app.DropDown.Value = '1';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.Items = {'Number of Trains', 'Run Time (s)'};
            app.DropDown_2.ItemsData = {'1', '2'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @NumTrainsOrRunTime, true);
            app.DropDown_2.Visible = 'off';
            app.DropDown_2.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExperimentalAutomator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end