classdef PID_Page < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileStatusEditField          matlab.ui.control.EditField
        FileStatusEditFieldLabel     matlab.ui.control.Label
        FileNameEditField            matlab.ui.control.EditField
        FileNameEditFieldLabel       matlab.ui.control.Label
        LocationtoSaveDataDropDown   matlab.ui.control.DropDown
        LocationtoSaveDataDropDownLabel  matlab.ui.control.Label
        CreateNewDataFileButton      matlab.ui.control.Button
        SelectFileandSaveInformationButton  matlab.ui.control.Button
        GenderDropDown               matlab.ui.control.DropDown
        GenderDropDownLabel          matlab.ui.control.Label
        EthnicityDropDown            matlab.ui.control.DropDown
        EthnicityDropDownLabel       matlab.ui.control.Label
        AgeEditField                 matlab.ui.control.NumericEditField
        AgeEditFieldLabel            matlab.ui.control.Label
        HomeButton                   matlab.ui.control.Button
        LoadStudyProtocolButton      matlab.ui.control.Button
        MotorThresholdSpinner        matlab.ui.control.Spinner
        MotorThresholdSpinnerLabel   matlab.ui.control.Label
        ParticipantIDEditField       matlab.ui.control.EditField
        ParticipantIDEditFieldLabel  matlab.ui.control.Label
        ParticipantInformationLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        % Variables used within multiple functions (app.Variable throughout
        % protocol)
        PID_Info
        NextPage
        magstimObject
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Starting variables until someone changes them
            app.AgeEditField.Value = 0;
            app.ParticipantIDEditField.Value = 'P0';
            app.MotorThresholdSpinner.Value = evalin('base','MotorThreshold'); % Get from workspace
            app.magstimObject = evalin('base','magstimObject'); % Get from workspace
            % Table to save
            app.PID_Info = [{app.ParticipantIDEditField.Value} {app.MotorThresholdSpinner.Value} {app.AgeEditField.Value} {app.GenderDropDown.Value} {app.EthnicityDropDown.Value}];
        end

        % Button pushed function: LoadStudyProtocolButton
        function LoadStudyProtocolButtonPushed(app, event)
            % Open Protocol Run Screen
            ProtocolRunScreen(app.ParticipantIDEditField.Value)

            % Close window
            delete(app)
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            % Open Home Page
            TMS_StartUpPage

            % Disconnect from magstim
            app.magstimObject.disconnect();

            % Pause to allow time to connect
            pause(1)

            % Close app
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Disconnect from magstim - comment this out if you want to
            % open and close the app when it is not connected
            %app.magstimObject.disconnect();

            % Close app
            delete(app)           
        end

        % Button pushed function: SelectFileandSaveInformationButton
        function SelectFileandSaveInformationButtonPushed(app, event)
            % Select file and load path
            appDir = fileparts(mfilename('fullpath'));
            seqLoc = [fileparts(appDir), '/experiments/'];
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0], 'CloseRequestFcn','');
            [filename, pathname] = uigetfile(fullfile(seqLoc,'*.xls;*.xlsx'),'select file');
            delete(f); %delete the dummy figure
            fullpath = strcat(pathname,filename);
            ParticipantTable = readtable(fullpath);
            
            % Update the file with the new participant information
            app.PID_Info = [{app.ParticipantIDEditField.Value} {app.MotorThresholdSpinner.Value} {app.AgeEditField.Value} {app.GenderDropDown.Value} {app.EthnicityDropDown.Value}];
            FileInfo = [ParticipantTable; app.PID_Info];
            FileInfo = table2cell(FileInfo);

            % Update variable to workspace
            assignin('base','MotorThreshold',app.MotorThresholdSpinner.Value);

            % Save the excel 
            appDir = fileparts(mfilename('fullpath'));
            loc = [fileparts(appDir), '/experiments/'];
            if ~isfolder(loc)
                mkdir(loc)
            end
            filename = [loc, filename];
            writecell(FileInfo,filename);
            disp(['sequence saved to ', loc])
 
            % Pause to allow time to save
            pause(0.5)

            % Update the status field
            app.FileStatusEditField.Value = 'Saved';
            pause(0.5)
        end

        % Button pushed function: CreateNewDataFileButton
        function CreateNewDataFileButtonPushed(app, event)
            % Put all the participant information in one place
            app.PID_Info = [{app.ParticipantIDEditField.Value} {app.MotorThresholdSpinner.Value} {app.AgeEditField.Value} {app.GenderDropDown.Value} {app.EthnicityDropDown.Value}];
            FileInfo = app.PID_Info;
            filename = app.FileNameEditField.Value;
            appDir = fileparts(mfilename('fullpath'));
            loc = [fileparts(appDir), '/experiments/'];
            if ~isfolder(loc)
                mkdir(loc)
            end
            filename = [loc, filename, '.xlsx'];
            writecell(FileInfo,filename);
            disp(['sequence saved to ', loc])


            % Pause to save and update the status field
            pause(0.5)
            app.FileStatusEditField.Value = 'Saved';
            pause(0.5)
        end

        % Value changed function: LocationtoSaveDataDropDown
        function LocationtoSaveDataDropDownValueChanged(app, event)
            value = app.LocationtoSaveDataDropDown.Value;
            
            % Changes what options are visible or not
            if value == 1 % Use exisiting file
                app.SelectFileandSaveInformationButton.Visible = 'on';
                app.CreateNewDataFileButton.Visible = 'off';
                app.FileNameEditField.Visible = 'off';
            else % Create new file
                app.SelectFileandSaveInformationButton.Visible = 'off';
                app.CreateNewDataFileButton.Visible = 'on';
                app.FileNameEditField.Visible = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ParticipantInformationLabel = uilabel(app.UIFigure);
            app.ParticipantInformationLabel.Text = 'Participant Information';
            app.ParticipantIDEditFieldLabel = uilabel(app.UIFigure);
            app.ParticipantIDEditFieldLabel.Text = 'Participant ID';
            app.ParticipantIDEditField = uieditfield(app.UIFigure, 'text');
            app.MotorThresholdSpinnerLabel = uilabel(app.UIFigure);
            app.MotorThresholdSpinnerLabel.Text = 'Motor Threshold';
            app.MotorThresholdSpinner = uispinner(app.UIFigure);
            app.MotorThresholdSpinner.Limits = [0 100];
            app.MotorThresholdSpinner.RoundFractionalValues = 'on';
            app.LoadStudyProtocolButton = uibutton(app.UIFigure, 'push');
            app.LoadStudyProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStudyProtocolButtonPushed, true);
            app.LoadStudyProtocolButton.Text = 'Load Study Protocol';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.AgeEditFieldLabel = uilabel(app.UIFigure);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.UIFigure, 'numeric');
            app.EthnicityDropDownLabel = uilabel(app.UIFigure);
            app.EthnicityDropDownLabel.Text = 'Ethnicity';
            app.EthnicityDropDown = uidropdown(app.UIFigure);
            app.EthnicityDropDown.Items = {'Prefer not to say', 'White', 'Black or African American', 'American Indian or Alaska Native', 'Asian', 'Native Hawaiian or Other Pacific Islander', 'Hispanic', 'Other'};
            app.EthnicityDropDown.Value = 'Prefer not to say';
            app.GenderDropDownLabel = uilabel(app.UIFigure);
            app.GenderDropDownLabel.Text = 'Gender';
            app.GenderDropDown = uidropdown(app.UIFigure);
            app.GenderDropDown.Items = {'Prefer not to say', 'Male', 'Female', 'Other'};
            app.GenderDropDown.Value = 'Prefer not to say';
            app.SelectFileandSaveInformationButton = uibutton(app.UIFigure, 'push');
            app.SelectFileandSaveInformationButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileandSaveInformationButtonPushed, true);
            app.SelectFileandSaveInformationButton.Text = 'Select File and Save Information';
            app.CreateNewDataFileButton = uibutton(app.UIFigure, 'push');
            app.CreateNewDataFileButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewDataFileButtonPushed, true);
            app.CreateNewDataFileButton.Visible = 'off';
            app.CreateNewDataFileButton.Text = 'Create New Data File';
            app.LocationtoSaveDataDropDownLabel = uilabel(app.UIFigure);
            app.LocationtoSaveDataDropDownLabel.Text = 'Location to Save Data';
            app.LocationtoSaveDataDropDown = uidropdown(app.UIFigure);
            app.LocationtoSaveDataDropDown.Items = {'Exisiting Data File', 'New Data File'};
            app.LocationtoSaveDataDropDown.ItemsData = [1 2];
            app.LocationtoSaveDataDropDown.ValueChangedFcn = createCallbackFcn(app, @LocationtoSaveDataDropDownValueChanged, true);
            app.LocationtoSaveDataDropDown.Value = 1;
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Visible = 'off';
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.Visible = 'off';
            app.FileNameEditField.Value = 'Participant Information';
            app.FileStatusEditFieldLabel = uilabel(app.UIFigure);
            app.FileStatusEditFieldLabel.Text = 'File Status';
            app.FileStatusEditField = uieditfield(app.UIFigure, 'text');
            app.FileStatusEditField.Value = 'Not Saved';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PID_Page

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end