classdef ProtocolRunScreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DisarmBetweenTrialsSwitch       matlab.ui.control.Switch
        DisarmBetweenTrialsSwitchLabel  matlab.ui.control.Label
        ContinueProtocolLabel           matlab.ui.control.Label
        PlayfromPause                   matlab.ui.control.Image
        EndButton                       matlab.ui.control.Image
        StartButton                     matlab.ui.control.Image
        PauseButton                     matlab.ui.control.Image
        EndProtocolLabel                matlab.ui.control.Label
        PauseProtocolLabel              matlab.ui.control.Label
        StartProtocolLabel              matlab.ui.control.Label
        TotalTrialsEditField            matlab.ui.control.NumericEditField
        TotalTrialsEditFieldLabel       matlab.ui.control.Label
        TrialCounterEditField           matlab.ui.control.NumericEditField
        TrialCounterEditFieldLabel      matlab.ui.control.Label
        MotorThresholdEditField         matlab.ui.control.NumericEditField
        MotorThresholdEditFieldLabel    matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        HomeButton                      matlab.ui.control.Button
        CoilTemperatureGauge            matlab.ui.control.SemicircularGauge
        CoilTemperatureGaugeLabel       matlab.ui.control.Label
        ProtocolRunScreenLabel          matlab.ui.control.Label
    end

    
    properties (Access = public)
        % Variables that are used throughout mutliple functions - have to
        % use app.variable throughout the protocol

        magstimObject
        StimType

        PID
        
        % Table with all the paramters
        PRT 

        % Variables for pausing/stopping the protocol
        isRunning = true;
        isPaused = false;

        %log trials
        savePath
        saveTrialsCols

        disarmBetweenTrials = 1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, PID)
             % Gets data from chosen file
             appDir = fileparts(mfilename('fullpath'));
             seqLoc = [fileparts(appDir), '/sequences/'];
             f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0], 'CloseRequestFcn','');
             [filename, pathname] = uigetfile(fullfile(seqLoc,'*.xls;*.xlsx'),'select file');
             delete(f); %delete the dummy figure
             fullpath = strcat(pathname,filename);
             ProtocolTable = readtable(fullpath);
             
             % Randomizes table using function
             PostRandomTable = RandomizeTMS(ProtocolTable);
             app.PRT = PostRandomTable;

             %PID passed as a variable
             if ~exist('PID', 'var')
                PID = 'P000';
             end
             disp(PID)
             
             %store trials in a .csv
             expDir = fullfile(fileparts(appDir), 'experiments');
             if ~exist(expDir, 'dir')
                 mkdir(expDir);
             end
             saveName = [erase(filename, '.xlsx'), '_', PID, '_', datestr(now, 'yyyy-mm-dd_HH-MM-SS'), '.csv'];
             app.savePath = fullfile(expDir, saveName);

             % Puts the randomized table data into the table on the app
             app.UITable.Data = table2cell(PostRandomTable);

             %create the save file
             app.saveTrialsCols = {'Grouping', 'Random or Block', 'Step Number',...
                 'Pulse Type', '%MT/%MT Conditioning Pulse', 'Number of Trials', 'Number of Pulses per Burst',...
                 'Inter Pulse Interval (ms)', 'Inter Trial Interval (s)', 'Frequency of Pulses (Hz)',...
                'Run Time (s)', '%MT Test Pulse', 'Coil Charge Delay (ms)', 'timestamp'};
             writecell(app.saveTrialsCols, app.savePath);


             % Gets the number of pulses table for the first grouping to
             % put into the counter
             if isequal(table2array(PostRandomTable(1,4)),{'Single'})
                 PulseNumber = PostRandomTable(1,6);
                 PulseNumber = table2array(PulseNumber);
             elseif isequal(table2array(PostRandomTable(1,4)),{'Paired Pulse'})
                 PulseNumber = PostRandomTable(1,6);
                 PulseNumber = table2array(PulseNumber);
             elseif isequal(table2array(PostRandomTable(1,4)),{'Theta Burst'})
                 PulseNumber = PostRandomTable(1,6);
                 PulseNumber = table2array(PulseNumber);
             else
                PulseNumber = table2array(PostRandomTable(1,7));
                RunTime = table2array(PostRandomTable(1,11))/1000;
                if PulseNumber == 0 
                    PulseNumber = round(RunTime*table2array(PostRandomTable(1,10)));
                end
             end
             app.TotalTrialsEditField.Value = size(PostRandomTable, 1);

             % Gets these values from workspace
             app.MotorThresholdEditField.Value = evalin("base", "MotorThreshold"); % Puts this value into the motor threshold spot on app
             app.magstimObject = evalin('base','magstimObject');
             app.StimType = evalin('base','StimType');
            
             uistack(app.UIFigure, 'top');  % Moves it to top within MATLAB (redundant with figure)
             drawnow;

            if ~app.magstimObject.armedStatus
                disp('Stimulator Arming...')
                app.magstimObject.setAmplitudeA(0);
                pause(0.5);
                if app.StimType == 2 % bistim
                    app.magstimObject.setAmplitudeB(0);
                    app.magstimObject.setPulseInterval(10);
                end
                pause(1);
                app.magstimObject.arm();
                pause(3)
                app.magstimObject.disarm();
            end
        end

        % Image clicked function: StartButton
        function StartButtonImageClicked(app, event)
        % When the start button is pressed, it becomes no longer visible
        app.StartButton.Visible = 'off';
        app.StartProtocolLabel.Visible = 'off';
        app.PauseButton.Visible = 'on';
        app.PauseProtocolLabel.Visible = 'on';
        app.DisarmBetweenTrialsSwitchLabel.Visible = 'off';
        app.DisarmBetweenTrialsSwitch.Visible = 'off';

        % Updates stop/start variable
        app.isRunning = true;
        
        % Saving variables to use throughout looping
        PostRandomTable = app.PRT;
        MotorThreshold = evalin('base','MotorThreshold');
        SequenceSize = size(PostRandomTable,1);

        % Variables to start the protocol with
        StimulatorOutput = round(table2array(PostRandomTable(1,5)) * (MotorThreshold/100)); 
        TestPulse = round(table2array(PostRandomTable(1,12)) * (MotorThreshold/100));
        
        % Reset pulse counter to zero before the start of each new
            % iteration
        app.TrialCounterEditField.Value = 0;

        % Applying starting paramters and arming
        if app.magstimObject.armedStatus
            app.magstimObject.disarm();
            pause(1)
        end
        disp('Setting Stimulator Parameters...')
        app.magstimObject.setAmplitudeA(StimulatorOutput);
        if app.StimType == 2 %bistim
            app.magstimObject.setAmplitudeB(TestPulse);
        end
        disp('Starting Sequence')
        pause(1);
        stopRunning = 0;
        
        % Looping Through the Protocol
        while app.isRunning == true % Will only continue looping if the protocol is not stopped
            drawnow % Checks to see if variables outside the loop have changed

            % If the protocol is paused, the pause loop will keep running
            % until play is hit or the protocol is stopped
            while app.isPaused == true
                app.magstimObject.pause(1)
                drawnow
            end

            for i = 1:SequenceSize
                % Bold the row that is current running
                BoldRow = uistyle('FontWeight','bold');
                addStyle(app.UITable,BoldRow,'row',i)
    
                % Unbold previous row 
                if i > 1
                    UnboldRow = uistyle('FontWeight','normal');
                    addStyle(app.UITable,UnboldRow,'row',i-1)
                end
                
                % Update pulse counter 
                app.TrialCounterEditField.Value = i;

                % Set the number of pulse trials for current iteration and
                % display in app screen
                if isequal(table2array(PostRandomTable(i,4)),{'Single'})
                    PulseNumber = PostRandomTable(i,6);
                    PulseNumber = table2array(PulseNumber);
                elseif isequal(table2array(PostRandomTable(i,4)),{'Paired Pulse'})
                    PulseNumber = PostRandomTable(i,6);
                    PulseNumber = table2array(PulseNumber);
                elseif isequal(table2array(PostRandomTable(i,4)),{'Theta Burst'})
                    PulseNumber = PostRandomTable(i,6);
                    PulseNumber = table2array(PulseNumber);
                else
                   PulseNumber = table2array(PostRandomTable(i,7));
                   RunTime = table2array(PostRandomTable(i,11))/1000;
                   if PulseNumber == 0 
                       PulseNumber = round(RunTime*table2array(PostRandomTable(i,10)));
                   end
                end
                app.TotalTrialsEditField.Value = SequenceSize;
    
                if app.magstimObject.armedStatus
                    if app.disarmBetweenTrials
                        app.magstimObject.disarm();
                        pause(.25);
                    end
                end

                % Determine and display coil temperature
                [Temp1, Temp2] = app.magstimObject.getTemperature();
                Temperature = mean([Temp2.CoilTemp1,Temp2.CoilTemp2]);
                app.CoilTemperatureGauge.Value = Temperature;
            
                % Setting select parameters before each trial
                clear params            
                StimulatorOutput = round(table2array(PostRandomTable(i,5)) * (MotorThreshold/100)); 
                TestPulse = round(table2array(PostRandomTable(i,12)) * (MotorThreshold/100));

                disp('Resetting Stimulator to Current Parameters')
                app.magstimObject.setAmplitudeA(StimulatorOutput);
                if app.StimType == 3 % rapid
                    app.magstimObject.setChargeDelay(table2array(PostRandomTable(i,13)));
                end
                if app.StimType == 2 % bistim
                    app.magstimObject.setAmplitudeB(TestPulse);
                end
            
                % Display Current Iteration Number
                InternationNumber = ['Iteration ' num2str(i) ' of ' num2str(SequenceSize)];
                disp(InternationNumber)
               
                % Single Pulse: interation if the current block is single
                % pulse
                if isequal(table2array(PostRandomTable(i,4)),{'Single'})
                    % Set single pulse variables
                    ITI = table2array(PostRandomTable(i,9));
                    
                    % Single pulse variables if rapid is being used
                    if app.StimType == 3 % rapid
                        params.frequency = 1;
                        params.nPulses = 1;
                        params.duration = [];
                        app.magstimObject.setTrain(params);
                    end

                    if ~app.magstimObject.armedStatus
                        app.magstimObject.arm();
                    end
                    if i > 1
                        disp(['Pause: ITI ', num2str(ITI)])
                        pause(ITI);   
                    else
                        while true
                            [~, params] = app.magstimObject.getParameters();
                            if params.InstrumentStatus.Ready
                                break
                            end
                            pause(.1);
                        end
                    end

                    % Looping based on number of pulses in block
                    for j = 1:table2array(PostRandomTable(i,6)) 
                        drawnow % Check pause/stop button
                        while app.isPaused == true
                            app.magstimObject.pause(1)
                            drawnow
                        end
                        if app.isRunning == false
                            stopRunning = 1;
                            break
                        end

                        % Set pulse counter to current block value
                        app.TrialCounterEditField.Value = j;
                        disp('Single Pulse: Firing...')

                        app.magstimObject.fire();
                        disp('Fired')
                    end
                    if stopRunning
                            break
                    end

                % Paired Pulse: iteration if paired pulse block
                elseif isequal(table2array(PostRandomTable(i,4)),{'Paired Pulse'})
                    % Setting variables
                    ITI = table2array(PostRandomTable(i,9));
                    IPI = table2array(PostRandomTable(i,8));
                    
                    % Setting variables for rapid pulse
                    if app.StimType == 3 % rapid
                        params.frequency = round(1/IPI,1);
                        params.nPulses = 2;
                        params.duration = [];
                        app.magstimObject.setTrain(params);
                        if ~app.magstimObject.armedStatus
                            app.magstimObject.arm();
                        end
                        if i > 1
                        disp(['Pause: ITI ', num2str(ITI)])
                            pause(ITI);   
                        else
                            pause(4);
                        end
                        for j = 1:table2array(PostRandomTable(i,6))
                            drawnow % Check pause/stop
                            while app.isPaused == true
                                app.magstimObject.pause(1)
                                drawnow
                            end
                            if app.isRunning == false
                                stopRunning = 1;
                                break
                            end

                            % Display trial number and update counter
                            TrialNumber = ['Theta Burst Group ' num2str(j) ' of ', num2str(table2array(PostRandomTable(i,6)))]; 
                            app.TrialCounterEditField.Value = j;
                            disp(TrialNumber)

                            % Fire and pause
                            app.magstimObject.fire();
                            disp('Fired')
                        end
                        if stopRunning
                                break
                        end
            
                    % Paired pulse loop if bistim stimulator used
                    elseif app.StimType == 2 % bistim
                        
                        % Variables
                        app.magstimObject.setPulseInterval(IPI);
                        if ~app.magstimObject.armedStatus
                            app.magstimObject.arm();
                        end
                        if i > 1
                            disp(['Pause: ITI ', num2str(ITI)])
                            pause(ITI);   
                        else
                            pause(4);
                        end

                        % Go through the number of paired pulse trials with the
                        % ability for two different pulse ampltidudes
                        for j = 1:table2array(PostRandomTable(i,6)) 
                            % Check paused/stopped
                            drawnow 
                            while app.isPaused == true
                                app.magstimObject.pause(1)
                                drawnow
                            end
                            if app.isRunning == false
                                stopRunning = 1;
                                break
                            end

                            % Display trial number and counter
                            TrialNumber = ['Pulse ' num2str(j) ' of ', num2str(size(PostRandomTable, 1))]; 
                            app.TrialCounterEditField.Value = j;
                            disp(TrialNumber)       
                           
                            % Fire and pause
                            app.magstimObject.fire();
                            disp('Fired')
                        end
                        if stopRunning
                                break
                        end
                    % Paired pulse for magstim     
                    else           
                        disp('This protocol is incompatible with your device!')
                    end
            
                % Theta Burst
                elseif isequal(table2array(PostRandomTable(i,4)),{'Theta Burst'})
                    ITI = table2array(PostRandomTable(i,9));
                    IPI = table2array(PostRandomTable(i,8))/1000;
                    BurstPulses = table2array(PostRandomTable(i,7));

                    % Theta burst for rapid stimulator
                    if app.StimType == 3 % rapid
                        % Set variables for rapid
                        params.frequency = 1000/IPI;
                        params.nPulses = BurstPulses;
                        params.duration = [];
                        app.magstimObject.setTrain(params);
                        
                        if ~app.magstimObject.armedStatus
                            app.magstimObject.arm();
                        end
                        %pause
                        if i > 1
                            disp(['Pause: ITI ', num2str(ITI)])
                            pause(ITI);   
                        else
                            pause(3.5)
                        end

                        % Looping for rapid
                        for j = 1:table2array(PostRandomTable(i,6))
                            % Pause/stop
                            drawnow
                            while app.isPaused == true
                                app.magstimObject.pause(1)
                                drawnow
                            end
                            if app.isRunning == false
                                stopRunning = 1;
                                break
                            end

                            % Check temperature
                            [Temp1, Temp2] = app.magstimObject.getTemperature();
                            Temperature = mean([Temp2.CoilTemp1,Temp2.CoilTemp2]);
                            app.CoilTemperatureGauge.Value = Temperature;

                            % Fire and pause (ITI)
                            app.magstimObject.poke();
                            app.magstimObject.fire();
                            disp('Fired')
                            
                        end
                        if stopRunning
                                break
                        end
                    % Looping for magstim and bistim     
                    else
                        disp('This protocol is incompatible with your device!')
                    end
            
                % Continuous pulse loop
                elseif isequal(table2array(PostRandomTable(i,4)),{'Continuous'})
                    % Set continuous pulse variables
                    BurstPulses = table2array(PostRandomTable(i,7));
                    RunTime = table2array(PostRandomTable(i,11));
                    Frequency = table2array(PostRandomTable(i,10));
            
                    % Check to see if pulses were set - otherwise time was
                    % used
%                     if BurstPulses == 0 
%                         BurstPulses = round(RunTime*Frequency);
%                     end
    
                    % Continuous pulses if rapid
                    if app.StimType == 3 % rapid
                        % Set paramteres
                        clear params
                        StimulatorOutput = round(table2array(PostRandomTable(i,5)) * (MotorThreshold/100)); 
                        app.magstimObject.setAmplitudeA(StimulatorOutput);
                        params.frequency = round(Frequency,1);
                        disp(Frequency)
                        disp(BurstPulses)
                        disp(RunTime)
                        if RunTime == 0
                            params.nPulses = BurstPulses;
                            params.duration = [];
                        else
                            params.duration = RunTime;
                            params.nPulses = [];
                        end
                        if ~app.magstimObject.armedStatus
                            app.magstimObject.arm();
                        end
                        pause(2.5)
                        disp('Parameters Set')
                        app.magstimObject.setTrain(params);
                        % Looping for rapid
                        for j = 1:1
                            % Pause/stop
                            drawnow
                            while app.isPaused == true
                                app.magstimObject.pause(1);
                                drawnow
                            end
                            if app.isRunning == false
                                stopRunning = 1;
                                break
                            end

                            % Fire continuous pulses
                            app.magstimObject.fire();
                            disp('Fired')
                        end
                        if stopRunning
                                break
                        end
                    else
                        disp('This protocol is incompatible with you device!')
                    end
                end

                %log the trial
                trialLog = PostRandomTable(i,:);
                timestamp = datestr(now, 'yyyy-mm-dd HH:MM:SS');
                trialLog{1,end+1} = timestamp;
                writetable(trialLog, app.savePath, 'WriteMode', 'append');

            end
            
            disp('Protocol Completed')
            app.isRunning = false;

        end

        UnboldRow = uistyle('FontWeight','normal');
        addStyle(app.UITable,UnboldRow);

        end

        % Value changed function: MotorThresholdEditField
        function MotorThresholdEditFieldValueChanged(app, event)
            % Updates the workspace if the motor threshold value is changed
            assignin('base','MotorThreshold',app.MotorThresholdEditField.Value);            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Disconnects the computer from magstim and closes apps
            app.magstimObject.disconnect();
            delete(app)
        end

        % Image clicked function: PauseButton
        function PauseButtonImageClicked(app, event)
            % App change app pausing variable
            app.isPaused = true;

            % Change the buttons that are visible
            app.PauseButton.Visible = 'off';
            app.PauseProtocolLabel.Visible = 'off';

            app.PlayfromPause.Visible = 'on';
            app.ContinueProtocolLabel.Visible = 'on';

            app.DisarmBetweenTrialsSwitchLabel.Visible = 'on';
            app.DisarmBetweenTrialsSwitch.Visible = 'on';
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            % If you return to home page, disconnect from magstim and close
            % app
            app.magstimObject.disconnect();
            pause(1)
            TMS_StartUpPage
            delete(app)
        end

        % Image clicked function: EndButton
        function EndButtonImageClicked(app, event)
            % Stop pulses variable
            app.isRunning = false;

            % Update visible options
            app.StartButton.Visible = 'on';
            app.StartProtocolLabel.Visible = 'on';
            app.PauseButton.Visible = 'off';
            app.PauseProtocolLabel.Visible = 'off';
            app.DisarmBetweenTrialsSwitchLabel.Visible = 'on';
            app.DisarmBetweenTrialsSwitch.Visible = 'on';
        end

        % Image clicked function: PlayfromPause
        function PlayfromPauseImageClicked(app, event)
            % Paused pulses variable
            app.isPaused = false;
            
            % Update visible options
            app.PlayfromPause.Visible = 'off';
            app.ContinueProtocolLabel.Visible = 'off';

            app.PauseButton.Visible = 'on';
            app.PauseProtocolLabel.Visible = 'on';

            app.DisarmBetweenTrialsSwitchLabel.Visible = 'off';
            app.DisarmBetweenTrialsSwitch.Visible = 'off';
        end

        % Value changed function: DisarmBetweenTrialsSwitch
        function disarmSwitchChanged(app, event)
            app.disarmBetweenTrials = app.DisarmBetweenTrialsSwitch.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ProtocolRunScreenLabel = uilabel(app.UIFigure);
            app.ProtocolRunScreenLabel.Text = 'Protocol Run Screen';
            app.CoilTemperatureGaugeLabel = uilabel(app.UIFigure);
            app.CoilTemperatureGaugeLabel.Text = 'Coil Temperature';
            app.CoilTemperatureGauge = uigauge(app.UIFigure, 'semicircular');
            app.CoilTemperatureGauge.Limits = [0 50];
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Grouping'; 'Random or Block'; 'Step Number'; 'Pulse Type'; '%MT/%MT Conditioning Pulse'; 'Number of Trials'; 'Number of Pulses'; 'Inter Pulse Interval (ms)'; 'Inter Trial Interval (s)'; 'Frequency of Pulses (Hz)'; 'Run Time (s)'; '%MT Test Pulse'; 'Coil Charge Delay (ms)'};
            app.UITable.RowName = {};
            app.MotorThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.MotorThresholdEditFieldLabel.Text = 'Motor Threshold';
            app.MotorThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.MotorThresholdEditField.Limits = [0 150];
            app.MotorThresholdEditField.RoundFractionalValues = 'on';
            app.MotorThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @MotorThresholdEditFieldValueChanged, true);
            app.TrialCounterEditFieldLabel = uilabel(app.UIFigure);
            app.TrialCounterEditFieldLabel.Text = 'Trial Counter';
            app.TrialCounterEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrialCounterEditField.Editable = 'off';
            app.TotalTrialsEditFieldLabel = uilabel(app.UIFigure);
            app.TotalTrialsEditFieldLabel.Text = 'Total Trials';
            app.TotalTrialsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalTrialsEditField.Editable = 'off';
            app.StartProtocolLabel = uilabel(app.UIFigure);
            app.StartProtocolLabel.Text = 'Start Protocol';
            app.PauseProtocolLabel = uilabel(app.UIFigure);
            app.PauseProtocolLabel.Text = 'Pause Protocol';
            app.EndProtocolLabel = uilabel(app.UIFigure);
            app.EndProtocolLabel.Text = 'End Protocol';
            app.PauseButton = uiimage(app.UIFigure);
            app.PauseButton.ImageClickedFcn = createCallbackFcn(app, @PauseButtonImageClicked, true);
            app.PauseButton.ImageSource = fullfile(pathToMLAPP, 'Pause.png');
            app.StartButton = uiimage(app.UIFigure);
            app.StartButton.ImageClickedFcn = createCallbackFcn(app, @StartButtonImageClicked, true);
            app.StartButton.ImageSource = fullfile(pathToMLAPP, 'Play Button.png');
            app.EndButton = uiimage(app.UIFigure);
            app.EndButton.ImageClickedFcn = createCallbackFcn(app, @EndButtonImageClicked, true);
            app.EndButton.ImageSource = fullfile(pathToMLAPP, 'End.png');
            app.PlayfromPause = uiimage(app.UIFigure);
            app.PlayfromPause.ImageClickedFcn = createCallbackFcn(app, @PlayfromPauseImageClicked, true);
            app.PlayfromPause.Visible = 'off';
            app.PlayfromPause.ImageSource = fullfile(pathToMLAPP, 'Play Button.png');
            app.ContinueProtocolLabel = uilabel(app.UIFigure);
            app.ContinueProtocolLabel.Visible = 'off';
            app.ContinueProtocolLabel.Text = 'Continue Protocol';
            app.DisarmBetweenTrialsSwitchLabel = uilabel(app.UIFigure);
            app.DisarmBetweenTrialsSwitchLabel.Text = 'Disarm Between Trials';
            app.DisarmBetweenTrialsSwitch = uiswitch(app.UIFigure, 'slider');
            app.DisarmBetweenTrialsSwitch.ItemsData = {'0', '1'};
            app.DisarmBetweenTrialsSwitch.ValueChangedFcn = createCallbackFcn(app, @disarmSwitchChanged, true);
            app.DisarmBetweenTrialsSwitch.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProtocolRunScreen(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end