classdef SetStimulatorIntensity < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        StatusEditField               matlab.ui.control.EditField
        StatusEditFieldLabel          matlab.ui.control.Label
        ContinuetoProtocolButton      matlab.ui.control.Button
        CoilTemperatureGauge          matlab.ui.control.SemicircularGauge
        CoilTemperatureGaugeLabel     matlab.ui.control.Label
        NoButton                      matlab.ui.control.Button
        DidaMEPoccurLabel             matlab.ui.control.Label
        YesButton                     matlab.ui.control.Button
        NumberofPulsesatCurrentIntensitySpinner  matlab.ui.control.Spinner
        NumberofPulsesatCurrentIntensitySpinnerLabel  matlab.ui.control.Label
        NumberofMEPsSpinner           matlab.ui.control.Spinner
        NumberofMEPsSpinnerLabel      matlab.ui.control.Label
        DeliverPulseButton            matlab.ui.control.Button
        ArmButton                     matlab.ui.control.Button
        DetermineMotorThresholdLabel  matlab.ui.control.Label
        HomeButton                    matlab.ui.control.Button
        StimulatorOutputMSOSpinner    matlab.ui.control.Spinner
        StimulatorOutputMSOSpinnerLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        % Variables used within multiple functions (app.Variable throughout
        % protocol)
        Button % Arm or fire button
        debug
        magstimObject
        MotorThreshold
        StimType
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Gets magstimObject from workspace
            app.magstimObject = evalin('base','magstimObject');
            app.StimType = evalin('base','StimType');
            if evalin('base', 'exist(''debug'', ''var'')')
                app.debug = evalin('base','debug');
            else
                app.debug = 0;
            end

            % Sets initial values upon start-up
            app.MotorThreshold = 0;
            
            if ~app.debug
                %magstim fires on first arm, so set amplitude to 0, arm, then
                %disarm
                app.magstimObject.setAmplitudeA(0);
                app.magstimObject.pause(1);
                app.magstimObject.arm();
                app.magstimObject.pause(1);
                app.magstimObject.disarm();
                if app.StimType == 2
                    app.magstimObject.setAmplitudeB(0);
                    app.magstimObject.setPulseInterval(10);
                end
            end
        end

        % Value changed function: StimulatorOutputMSOSpinner
        function StimulatorOutputMSOSpinnerValueChanged(app, event)
            if ~app.debug
                % Reset to disarmed state if already armed
                if app.magstimObject.armedStatus
                    app.magstimObject.disarm(); %make this conditional by getting params and checking if armed
                end
            end
            app.ArmButton.Visible = 'on';
            app.DeliverPulseButton.Visible = 'off';

            % If a new stimulator output is chosen, it resets the MEP
            % spinner and pulse counter spinner
            app.NumberofPulsesatCurrentIntensitySpinner.Value = 0;
            app.NumberofMEPsSpinner.Value = 0;

            % Updates the motor threshold value used throughout the
            % protocol
            app.MotorThreshold = app.StimulatorOutputMSOSpinner.Value;
        end

        % Button pushed function: DeliverPulseButton
        function DeliverPulseButtonPushed(app, event)
            % Update stimulator output value
            Threshold = app.StimulatorOutputMSOSpinner.Value; % Stimulator output value
            if ~app.debug
                app.magstimObject.setAmplitudeA(Threshold);
            end

            % Update pulse counter
            app.NumberofPulsesatCurrentIntensitySpinner.Value = app.NumberofPulsesatCurrentIntensitySpinner.Value + 1;

            % Firing
            disp('Firing')
            app.StatusEditField.Value = 'Firing'; % Update the status field

            if ~app.debug
                app.magstimObject.fire();
            end
            app.DeliverPulseButton.BackgroundColor = [0.5 0.5 0.5]; 
            app.DeliverPulseButton.Enable = 'off';                    % Disable interaction
            app.DeliverPulseButton.Text = '';                         % Remove text
            pause(1);

            disp('Fired')
            app.StatusEditField.Value = 'Fired'; % Update the status field

            if ~app.debug
                % Check and update temperature
                [Temp1, Temp2] = app.magstimObject.getTemperature();
                Temperature = round(mean([Temp2.CoilTemp1,Temp2.CoilTemp2]));
                app.CoilTemperatureGauge.Value = Temperature;
            else
                app.CoilTemperatureGauge.Value = 40;
            end
            
            %wait for stim to recharge
            while true
                [~, params] = app.magstimObject.getParameters();
                if params.InstrumentStatus.Ready
                    break
                end
                pause(.1);
            end
            app.DeliverPulseButton.BackgroundColor = [0.96 0.96 0.96];
            app.DeliverPulseButton.Enable = 'on';                    % Disable interaction
            app.DeliverPulseButton.Text = 'Deliver Pulse';  
            app.StatusEditField.Value = 'Ready';

        end

        % Button pushed function: ArmButton
        function ArmButtonPushed(app, event)
            disp('Arming')
            app.StatusEditField.Value = 'Arming'; % Update status 
            Threshold = app.StimulatorOutputMSOSpinner.Value; % Stimulator output value

            if ~app.debug
                app.magstimObject.setAmplitudeA(Threshold);
                pause(0.25)
                app.magstimObject.arm();
            end
            pause(1);
            
            disp('Armed')
            app.StatusEditField.Value = 'Armed'; % Update status

            if ~app.debug
                % Check and update temperature
                [Temp1, Temp2] = app.magstimObject.getTemperature();
                Temperature = round(mean([Temp2.CoilTemp1,Temp2.CoilTemp2]));
                app.CoilTemperatureGauge.Value = Temperature;
            else
                app.CoilTemperatureGauge.Value = 40;
            end

            % Show fire button instead of arm button
            app.ArmButton.Visible = 'off';
            app.DeliverPulseButton.Visible = 'on';
        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)
            % Update MEP spinner number 
            app.NumberofMEPsSpinner.Value = app.NumberofMEPsSpinner.Value + 1;            
        end

        % Button pushed function: ContinuetoProtocolButton
        function ContinuetoProtocolButtonPushed(app, event)
            % Update motor threshold in workspace
            assignin('base','MotorThreshold',app.MotorThreshold);

            % Open PID_Page
            PID_Page

            % Close app
            delete(app)
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            % Open home page
            TMS_StartUpPage

            if ~app.debug
                % Disconnect magstim 
                app.magstimObject.disconnect();
            end

            % Close app
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Disconnect magstim - comment this out if you want to open and
            % close the app when it is not connected
            if ~app.debug
                % Disconnect magstim 
                app.magstimObject.disconnect();
            end

            % Close app
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StimulatorOutputMSOSpinnerLabel = uilabel(app.UIFigure);
            app.StimulatorOutputMSOSpinnerLabel.Text = 'Stimulator Output (%MSO)';
            app.StimulatorOutputMSOSpinner = uispinner(app.UIFigure);
            app.StimulatorOutputMSOSpinner.Limits = [0 100];
            app.StimulatorOutputMSOSpinner.RoundFractionalValues = 'on';
            app.StimulatorOutputMSOSpinner.ValueChangedFcn = createCallbackFcn(app, @StimulatorOutputMSOSpinnerValueChanged, true);
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.DetermineMotorThresholdLabel = uilabel(app.UIFigure);
            app.DetermineMotorThresholdLabel.Text = 'Determine Motor Threshold';
            app.ArmButton = uibutton(app.UIFigure, 'push');
            app.ArmButton.ButtonPushedFcn = createCallbackFcn(app, @ArmButtonPushed, true);
            app.ArmButton.Text = 'Arm';
            app.DeliverPulseButton = uibutton(app.UIFigure, 'push');
            app.DeliverPulseButton.ButtonPushedFcn = createCallbackFcn(app, @DeliverPulseButtonPushed, true);
            app.DeliverPulseButton.Visible = 'off';
            app.DeliverPulseButton.Text = 'Deliver Pulse';
            app.NumberofMEPsSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofMEPsSpinnerLabel.Text = 'Number of MEPs';
            app.NumberofMEPsSpinner = uispinner(app.UIFigure);
            app.NumberofMEPsSpinner.Limits = [0 Inf];
            app.NumberofMEPsSpinner.RoundFractionalValues = 'on';
            app.NumberofPulsesatCurrentIntensitySpinnerLabel = uilabel(app.UIFigure);
            app.NumberofPulsesatCurrentIntensitySpinnerLabel.Text = 'Number of Pulses at Current Intensity';
            app.NumberofPulsesatCurrentIntensitySpinner = uispinner(app.UIFigure);
            app.NumberofPulsesatCurrentIntensitySpinner.Limits = [0 Inf];
            app.NumberofPulsesatCurrentIntensitySpinner.RoundFractionalValues = 'on';
            app.YesButton = uibutton(app.UIFigure, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Text = 'Yes';
            app.DidaMEPoccurLabel = uilabel(app.UIFigure);
            app.DidaMEPoccurLabel.Text = 'Did a MEP occur? ';
            app.NoButton = uibutton(app.UIFigure, 'push');
            app.NoButton.Text = 'No';
            app.CoilTemperatureGaugeLabel = uilabel(app.UIFigure);
            app.CoilTemperatureGaugeLabel.Text = 'Coil Temperature';
            app.CoilTemperatureGauge = uigauge(app.UIFigure, 'semicircular');
            app.CoilTemperatureGauge.Limits = [0 40];
            app.CoilTemperatureGauge.MajorTicks = [0 5 10 15 20 25 30 35 40];
            app.CoilTemperatureGauge.MajorTickLabels = {'0', '5', '10', '15', '20', '25', '30', '35', '40'};
            app.CoilTemperatureGauge.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40];
            app.ContinuetoProtocolButton = uibutton(app.UIFigure, 'push');
            app.ContinuetoProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuetoProtocolButtonPushed, true);
            app.ContinuetoProtocolButton.Text = 'Continue to Protocol';
            app.StatusEditFieldLabel = uilabel(app.UIFigure);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.UIFigure, 'text');
            app.StatusEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SetStimulatorIntensity

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end