classdef TMS_StartUpPage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CreateNewStudyProtocolButton  matlab.ui.control.Button
        LoadStudyProtocolButton       matlab.ui.control.Button
        TMSUserInterfaceLabel         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreateNewStudyProtocolButton
        function CreateNewStudyProtocolButtonPushed(app, event)
            % If Creat New Study Protocol is pushed, the Experimental Automator screen will
            % appear and close home screen
            appDir = fileparts(mfilename('fullpath'));
            disp(appDir)
            cd(appDir)

                % Add MAGIC-master and subfolders to path
            magicPath = fullfile(appDir, 'MAGIC-master');
            addpath(genpath(magicPath))

            ExperimentalAutomator
            delete(app)
        end

        % Button pushed function: LoadStudyProtocolButton
        function LoadStudyProtocolButtonPushed(app, event)
            % If Load Study Protocol is pushed, the run or edit screen will
            % appear and close home screen
            % Get the instance of the AppManagementService
            appDir = fileparts(mfilename('fullpath'));
            disp(appDir)
            cd(appDir)

            % Add MAGIC-master and subfolders to path
            magicPath = fullfile(appDir, 'MAGIC-master');
            addpath(genpath(magicPath))

            RunorEdit
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TMSUserInterfaceLabel = uilabel(app.UIFigure);
            app.TMSUserInterfaceLabel.Text = 'TMS User Interface';
            app.LoadStudyProtocolButton = uibutton(app.UIFigure, 'push');
            app.LoadStudyProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStudyProtocolButtonPushed, true);
            app.LoadStudyProtocolButton.Text = 'Load Study Protocol';
            app.CreateNewStudyProtocolButton = uibutton(app.UIFigure, 'push');
            app.CreateNewStudyProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewStudyProtocolButtonPushed, true);
            app.CreateNewStudyProtocolButton.Text = 'Create New Study Protocol';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TMS_StartUpPage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end